classdef Iskaffe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                      matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        BatchprocessingMenu             matlab.ui.container.Menu
        ParametersanalysisKoppMenu      matlab.ui.container.Menu
        LineanalysisMenu                matlab.ui.container.Menu
        MainFigure_GridLayout           matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Files_Tab                       matlab.ui.container.Tab
        FilesTab_GridLayout             matlab.ui.container.GridLayout
        FilesTab_Buttons_GridLayout     matlab.ui.container.GridLayout
        FilesTab_Load_Button            matlab.ui.control.Button
        FilesTab_Convert_Button         matlab.ui.control.Button
        FilesTab_Table                  matlab.ui.control.Table
        FilesTab_Folder_GridLayout      matlab.ui.container.GridLayout
        EditFieldLabel                  matlab.ui.control.Label
        FilesTab_Folder_EditField       matlab.ui.control.EditField
        FilesTab_Folder_Button          matlab.ui.control.Button
        Lines_Tab                       matlab.ui.container.Tab
        LinesTab_GridLayout             matlab.ui.container.GridLayout
        LinesTab_ListBox                matlab.ui.control.ListBox
        Label_2                         matlab.ui.control.Label
        Process_Tab                     matlab.ui.container.Tab
        ProcessTab_GridLayout           matlab.ui.container.GridLayout
        ProcessTab_AnalysisResults_Panel  matlab.ui.container.Panel
        ProcessTab_AnalysisResults_GridLayout  matlab.ui.container.GridLayout
        Label_3                         matlab.ui.control.Label
        ProcessTab_AnalysisResults_TextArea  matlab.ui.control.TextArea
        ProcessTab_Display_Panel        matlab.ui.container.Panel
        ProcessTab_Display_GridLayout   matlab.ui.container.GridLayout
        ProcessTab_ButtonMotionTextArea  matlab.ui.control.TextArea
        ProcessTab_ButtonDownTextArea   matlab.ui.control.TextArea
        ProcessTab_SurveyProcessing_Panel  matlab.ui.container.Panel
        ProcessTab_SurveyProcessing_GridLayout  matlab.ui.container.GridLayout
        ProcessTab_ExportSurveyAnalysis_Button  matlab.ui.control.Button
        ProcessTab_ShowResults_CheckBox  matlab.ui.control.CheckBox
        ProcessTab_AnalyzeSurvey_Button  matlab.ui.control.Button
        ProcessTab_Clear_Button         matlab.ui.control.Button
        ProcessTab_Edit_Button          matlab.ui.control.Button
        ProcessTab_Draw_Button          matlab.ui.control.Button
        ProcessTab_File_Button          matlab.ui.control.Button
        ProcessTab_SurveyLimits_Label   matlab.ui.control.Label
        ProcessTab_LineProcessing_Panel  matlab.ui.container.Panel
        ProcessTab_LineProcessing_GridLayout  matlab.ui.container.GridLayout
        ProcessTab_ShowLineAnalysis_CheckBox  matlab.ui.control.CheckBox
        GridsizemLabel                  matlab.ui.control.Label
        ProcessTab_GriddedDataDisplayed_DropDownMenu  matlab.ui.control.DropDown
        ProcessTab_Analyze_Button       matlab.ui.control.Button
        ProcessTab_Grid_Button          matlab.ui.control.Button
        GriddeddatadisplayedDropDownLabel  matlab.ui.control.Label
        ProcessTab_GridSize_EditField   matlab.ui.control.NumericEditField
        Main_Axes                       matlab.ui.control.UIAxes
        Survey_Axes                     matlab.ui.control.UIAxes
        FilesTab_ContextMenu            matlab.ui.container.ContextMenu
        FilesTab_SelectAll_Menu         matlab.ui.container.Menu
        FilesTab_SelectConverted_Menu   matlab.ui.container.Menu
        FilesTab_DeselectAll_Menu       matlab.ui.container.Menu
        FilesTab_InverseSelection_Menu  matlab.ui.container.Menu
        LinesTab_ContextMenu            matlab.ui.container.ContextMenu
        LinesTab_UnloadSelected_Menu    matlab.ui.container.Menu
        LinesTab_SortAllLines_Menu      matlab.ui.container.Menu
        LinesTab_MostRecentLinesOnTop_Menu  matlab.ui.container.Menu
        LinesTab_OldestLinesOnTop_Menu  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        
        % general properties (static)
        Name = 'Iskaffe'; % app name
        Version = '0.3.3'; % app version
        CoffeeVersion = '2.0.0-alpha.12'; % coffee version app was built with, from CFF_coffee_version()
        
        % files and folders, set in startup function
        RootFolder = ''; % root folder of app
        UserFolder = ''; % user folder for app
        ConfigFile = ''; % app config file (json format)
        DiaryFile = '';  % diary filename
        
        FDataVersion
        
        % loaded lines data
        FData = []; % cell array of loaded fData
        FDataModeList = [];  % list of all modes in loaded data
        FDataModeInd1P = []; % index in mode list for each ping of each line
        
        % raw data properties
        rawDataFilesList = [];
        idxConverted
        idxLoaded
        
        % configuration
        datagramSource = 'X8';
        ellips = '';
        tmproj = '';
        
        % data analysis
        mosaicMinBeamAngle = [];
        mosaicInPolygon = []; % mosaic cells inside ROI
        mosaicMissing = []; % mosaic cells missing
        
    end
    
    methods (Access = private)
        
        % Initialize app's config file
        function init_config_file(app)
            if ~isfolder(fileparts(app.ConfigFile))
                % create folder
                mkdir(fileparts(app.ConfigFile));
            end
            if isfile(app.ConfigFile)
                % delete existing file
                delete(app.ConfigFile);
            end
            % initialize blank config file
            fid = fopen(app.ConfigFile,'w');
            fclose(fid);
        end
        
        % Get a field value from app's config file
        function out = get_config_field(app,fieldName)
            % init fail output
            out = '';
            if isfile(app.ConfigFile)
                % read config file
                configJSON = fileread(app.ConfigFile);
                if ~isempty(configJSON)
                    % decode config file
                    config = jsondecode(configJSON);
                    if isfield(config,fieldName)
                        % read field
                        out = config.(fieldName);
                    end
                end
            end
        end
        
        % Set a field value in app's config file
        function out = set_config_field(app,fieldName,fieldVal)
            % init fail output
            out = false;
            if isfile(app.ConfigFile)
                % read config file
                configJSON = fileread(app.ConfigFile);
                if ~isempty(configJSON)
                    % decode config file
                    config = jsondecode(fileread(app.ConfigFile));
                else
                    % inititate blank config
                    config = struct();
                end
                % set value
                config.(fieldName) = fieldVal;
                % encode config
                configJSON = jsonencode(config);
                % rewrite config file
                fid = fopen(app.ConfigFile,'w');
                fprintf(fid,'%c',configJSON);
                fclose(fid);
                % success
                out = true;
            end
        end
        
        % Check if a folder contains the CoFFee toolbox
        function isCoffeeFolder = is_coffee_folder(~,folder)
            % for now, we just test the existence of a folder and of the
            % coffee version function
            if isfolder(folder) && exist(fullfile(folder,'CFF_coffee_version.m'),'file')
                isCoffeeFolder = true;
            else
                isCoffeeFolder = false;
            end
        end
        
        % Get the CoFFee version from its folder
        function coffeeVer = get_coffee_version(app,folder)
            % first, check that it is a coffee folder
            if ~is_coffee_folder(app,folder)
                coffeeVer = NaN;
                return
            end
            % get version of that coffee
            curdir = cd;
            cd(folder);
            coffeeVer = CFF_coffee_version();
            cd(curdir);
        end
        
        % Check if a CoFFee folder has the expected version
        function isVersionOK = is_coffee_version(app,folder,coffeeVerWanted)
            % first, check that it is a coffee folder
            if ~is_coffee_folder(app,folder)
                isVersionOK = NaN;
                return
            end
            % next, get version of that coffee
            coffeeVerActual = get_coffee_version(app,folder);
            % finally, comapre to expected version
            if strcmp(coffeeVerActual,coffeeVerWanted)
                isVersionOK = true;
            else
                isVersionOK = false;
            end
        end
        
        % Update Raw Files Tab
        function updateRawFilesTable(app,keepSelection)
            
            % start waiting dialog
            waitDlg = uiprogressdlg(app.MainFigure,...
                'Title','Updating raw files table. Please wait.',...
                'Indeterminate','on');
            
            % save list of files selected
            if exist('keepSelection','var') && keepSelection==1 && ~isempty(app.FilesTab_Table.Data)
                idxFilesSelected = cell2mat(app.FilesTab_Table.Data(:,1));
            end
            
            % list raw files 1) only in search folder, and 2) recursively
            rawDataFolder = app.FilesTab_Folder_EditField.Value;
            recursive_flag = 0;
            rawDataFilesListRoot = CFF_list_raw_files_in_dir(rawDataFolder,recursive_flag,{'.all','.kmall'});
            recursive_flag = 1;
            rawDataFilesListRecu = CFF_list_raw_files_in_dir(rawDataFolder,recursive_flag,{'.all','.kmall'});
            
            % check if files lists are different
            if isequal(rawDataFilesListRoot,rawDataFilesListRecu)
                % no difference, proceed
                app.rawDataFilesList = rawDataFilesListRoot;
            else
                % files list are different, prompt user for which they want
                msg = sprintf('There are %i files in the specified folder, but %i files in total when including sub-folders. Do you want to include files in sub-folders?',numel(rawDataFilesListRoot),numel(rawDataFilesListRecu));
                title = 'Updating raw files table';
                selection = uiconfirm(app.MainFigure,msg,title,...
                    'Options',{'Folder only', 'Include sub-folders'},...
                    'DefaultOption',2);
                switch selection
                    case 'Folder only'
                        app.rawDataFilesList = rawDataFilesListRoot;
                    case 'Include sub-folders'
                        app.rawDataFilesList = rawDataFilesListRecu;
                end
            end
            
            % check which are already converted
            flagCheckVersion = 1; % outdated converted files to show as "not-converted"... but takes longer to check
            app.idxConverted = CFF_are_raw_files_converted(app.rawDataFilesList,flagCheckVersion);
            
            % check which are currently loaded
            app.idxLoaded = CFF_are_raw_files_loaded(app.rawDataFilesList, app.FData);
            
            % prepare table contents
            n_rawfiles = numel(app.rawDataFilesList);
            [disp_folder, filename, ext] = CFF_fileparts_as_cell(CFF_onerawfileonly(app.rawDataFilesList));
            disp_files = strcat(filename, ext);
            for ii = 1:n_rawfiles
                if iscell(app.rawDataFilesList{ii})
                    % paired file
                    disp_files{ii} = strcat(disp_files{ii}, ' (paired)');
                end
            end
            new_entry = [num2cell(false(n_rawfiles, 1)), disp_files, disp_folder];

            % remove current style
            removeStyle(app.FilesTab_Table);
            
            % set new data in table
            app.FilesTab_Table.Data = new_entry;
            
            % set files colors depending on status
            if ~isempty(new_entry)
                idx2subs = @(idx) cat(2,find([idx;0]),2.*ones(numel(find(idx)),1)); % function to turn files idx to table subscript indices
                styleNotConverted = uistyle('FontColor',[0.5 0.5 0.5]); % gray
                addStyle(app.FilesTab_Table,styleNotConverted,'cell',idx2subs(~app.idxConverted));
                styleConvertedNotLoaded = uistyle('FontWeight','bold','FontColor','k'); % black
                addStyle(app.FilesTab_Table,styleConvertedNotLoaded,'cell',idx2subs(app.idxConverted&~app.idxLoaded));
                styleLoaded = uistyle('FontWeight','bold','FontColor',[0.4660 0.6740 0.1880]); % green
                addStyle(app.FilesTab_Table,styleLoaded,'cell',idx2subs(app.idxConverted&app.idxLoaded));
            end
            
            % re-select files previously selected
            if exist('keepSelection','var') && keepSelection==1 && ~isempty(idxFilesSelected)
                app.FilesTab_Table.Data(idxFilesSelected,1) = {true};
            end
            
            % close waiting dialog
            close(waitDlg);
            
        end
        
        % Update Loaded Lines Tab
        function updateLoadedLines(app)
            nLines = numel(app.FData);
            if nLines == 0
                app.LinesTab_ListBox.Items = {};
                return
            end
            % get names of loaded lines
            loadedLinesNames = cellfun(@(x) CFF_file_name(CFF_onerawfileonly(x.ALLfilename)), app.FData,'UniformOutput',true);
            % update listbox items
            app.LinesTab_ListBox.Items = loadedLinesNames;
        end
        
        function updateMap(app)
            % eventually I want to get rid of this general-purpose function
            % and put all its components in the actual places where they
            % are needed. for example the loop over creating navigation
            % graph obj should be in the function that load new lines, etc.
            
            % number of lines loaded in app
            nLines = numel(app.FData);
            
            % exit here if there are no lines loaded
            % NOT sure that's a good idea. look into this...
            if nLines == 0
                return
            end
            
            % IDs of all loaded lines
            linesID = cellfun(@(f) num2str(f.ID,'%.0f'),app.FData,'UniformOutput',false);
            
            % create graph objects, one line at a time
            for ii = 1:nLines
                
                % create navigation graphical objects
                lineNavID = [linesID{ii} '_nav'];
                if isempty(findobj(app.Main_Axes,'Tag',lineNavID))
                    app.createLineNavigation(ii, lineNavID);
                end
                
                % create coverage graphical objects
                lineCovID = [linesID{ii} '_cov'];
                if isempty(findobj(app.Main_Axes,'Tag',lineCovID))
                    app.createLineCoverage(ii, lineCovID);
                end
                
                % create gridded data graphical objects
                lineDatID = [linesID{ii} '_dat'];
                if isempty(findobj(app.Main_Axes,'Tag',lineDatID))
                    app.createLineGriddedData(ii, lineDatID);
                end
                
                % create bad data graphical objects
                lineBadDataID = [linesID{ii} '_bad'];
                if  isempty(findobj(app.Main_Axes,'Tag',lineBadDataID))
                    app.createLineBadData(ii, lineBadDataID);
                end
                
            end
            
            % update order of elements
            app.reorderLinesGraphObjects();
            
            % update gridded data displayed (and colorbar)
            app.updateLineGriddedDataDisplayed();
            
        end
        
        % Update Map
%         function updateMap_BACKUP(app)
%             % backup of the previous version of updateMap. To get rid of
%             % eventually..
%             
%             % number of lines loaded
%             nLines = numel(app.FData);
%             
%             % exit here if no more lines
%             % NOT sure that's a good idea... look into this
%             if nLines == 0
%                 return
%             end
%             
%             % get tags of all graphic objects in map
%             allGraphObj = get(app.Main_Axes,'Children');
%             if ~isempty(allGraphObj)
%                 allGraphObjTags = unique(get(allGraphObj,'Tag'));
%             else
%                 allGraphObjTags = {};
%             end
%             
%             % default nav line color
%             nav_col = [0 0 0]; % black
%             
%             % (re)initialize colorbar
%             colormap(app.Main_Axes,'default')
%             colorbar(app.Main_Axes,'off')
%             colorbarOn = 0;
%             
%             % (re)initialize xlim and ylim for zoom extent
%             xlim = [nan nan];
%             ylim = [nan nan];
%             cax  = [nan nan];
%             
%             % work line per line
%             for ii = 1:nLines
%                 
%                 % get data
%                 fData = app.FData{ii};
%                 
%                 % Line ID
%                 lineID = num2str(fData.ID,'%.0f');
%                 
%                 %% PART 1: NAVIGATION
%                 
%                 % Line Navigation ID
%                 lineNavID = [lineID '_nav'];
%                 
%                 % Only draw the navigation if it is NOT already drawn
%                 if ~ismember(lineNavID, allGraphObjTags)
%                     
%                     % 1.1 draw main navigation line
%                     plot(app.Main_Axes,fData.X_1P_pingE,fData.X_1P_pingN,...
%                         'Tag',lineNavID,...
%                         'Visible','on',...
%                         'Color',nav_col);
%                     
%                     % 1.2 draw points every dt seconds as subsampled navigation
%                     dt = 60; % in seconds
%                     pingTimeSec = 0.001.*(fData.X_1P_pingTSMIM-fData.X_1P_pingTSMIM(1));
%                     idx_f = mod(ceil(pingTimeSec),dt)==0 & [0,diff(ceil(pingTimeSec))];
%                     
%                     plot(app.Main_Axes,fData.X_1P_pingE(idx_f),fData.X_1P_pingN(idx_f),'.',...
%                         'Tag',lineNavID,...
%                         'Visible','on',...
%                         'Color',nav_col);
%                     
%                     % 1.3 start and end symbols
%                     
%                     % OLD
%                     % % draw circle as start of line
%                     % plot(app.Main_Axes,fData.X_1P_pingE(1),fData.X_1P_pingN(1),'o',...
%                     %     'Tag',lineNavID,...
%                     %     'Visible','on',...
%                     %     'Color',nav_col);
%                     %
%                     % % end of line
%                     % plot(app.Main_Axes,fData.X_1P_pingE(end),fData.X_1P_pingN(end),'s',...
%                     %     'Tag',lineNavID,...
%                     %     'Visible','on',...
%                     %     'Color',nav_col);
%                     
%                     nPings = numel(fData.X_1P_pingE);
%                     iPingDir = min(5,nPings); % number of pings to use to establish heading for start/end symbols
%                     
%                     % at start of line, draw a triangle
%                     
%                     % set triangle side length to half the depth
%                     L = 0.5*nanmean(fData.X8_BP_DepthZ(:,1)); % in m
%                     
%                     % start heading in deg
%                     h = atan2d( ...
%                         (fData.X_1P_pingE(iPingDir)-fData.X_1P_pingE(1)), ...
%                         (fData.X_1P_pingN(iPingDir)-fData.X_1P_pingN(1)) );
%                     
%                     % X and Y positions of triangle vertices
%                     lineStartX = fData.X_1P_pingE(1) + [-0.5*L*cosd(h),0.5*L*cosd(h),0.5*L*sind(h),-0.5*L*cosd(h)];
%                     lineStartY = fData.X_1P_pingN(1) + [0.5*L*sind(h),-0.5*L*sind(h),0.5*L*cosd(h),0.5*L*sind(h)];
%                     
%                     % draw
%                     plot(app.Main_Axes,lineStartX,lineStartY,...
%                         'Tag',lineNavID,...
%                         'Visible','on',...
%                         'Color',nav_col);
%                     
%                     % at end of line, draw a segment
%                     
%                     % set segment length to half the depth
%                     L = 0.5*nanmean(fData.X8_BP_DepthZ(:,end));
%                     
%                     % end heading in deg
%                     h = atan2d( ...
%                         (fData.X_1P_pingE(end)-fData.X_1P_pingE(end-iPingDir+1)), ...
%                         (fData.X_1P_pingN(end)-fData.X_1P_pingN(end-iPingDir+1)) );
%                     
%                     % X and Y positions of segment vertices
%                     lineStartX = fData.X_1P_pingE(end) + [-0.5*L*cosd(h),0.5*L*cosd(h)];
%                     lineStartY = fData.X_1P_pingN(end) + [0.5*L*sind(h),-0.5*L*sind(h)];
%                     
%                     % draw
%                     plot(app.Main_Axes,lineStartX,lineStartY,...
%                         'Tag',lineNavID,...
%                         'Visible','on',...
%                         'Color',nav_col);
%                     
%                     % add click-on-object callback to all navigation
%                     % elements
%                     lineNavObj = findobj(app.Main_Axes,'Tag',lineNavID);
%                     for jj = 1:numel(lineNavObj)
%                         lineNavObj(jj).ButtonDownFcn = createCallbackFcn(app, @MapObject_ButtonDown, true);
%                     end
%                     
%                 end
%                 
%                 % Update zoom extents
%                 xlim(1) = nanmin(xlim(1),nanmin(fData.X_1P_pingE(:)));
%                 xlim(2) = nanmax(xlim(2),nanmax(fData.X_1P_pingE(:)));
%                 ylim(1) = nanmin(ylim(1),nanmin(fData.X_1P_pingN(:)));
%                 ylim(2) = nanmax(ylim(2),nanmax(fData.X_1P_pingN(:)));
%                 
%                 
%                 %% COVERAGE
%                 
%                 % Line Navigation ID
%                 lineCovID = [lineID '_cov'];
%                 
%                 % Only draw the coverage if it is NOT already drawn
%                 if ~ismember(lineCovID, allGraphObjTags)
%                     
%                     
%                     % get coordinates of the outermost soundings starting
%                     % from the top-left corner and going clockwise
%                     E = [fData.X_BP_bottomEasting(1,:)'; ...
%                         fData.X_BP_bottomEasting(:,end); ...
%                         fliplr(fData.X_BP_bottomEasting(end,:))'; ...
%                         flipud(fData.X_BP_bottomEasting(:,1))];
%                     N = [fData.X_BP_bottomNorthing(1,:)'; ...
%                         fData.X_BP_bottomNorthing(:,end); ...
%                         fliplr(fData.X_BP_bottomNorthing(end,:))'; ...
%                         flipud(fData.X_BP_bottomNorthing(:,1))];
%                     pos = [E,N];
%                     
%                     %
%                     
%                     % then create polygon and load into map
%                     cov = images.roi.Polygon( ...
%                         'Position', pos, ...
%                         'Color','black',...
%                         'FaceAlpha', 0.1, ...
%                         'InteractionsAllowed','none', ...
%                         'LineWidth',1, ...
%                         'Tag',lineCovID ...
%                         );
%                     cov.Parent = app.Main_Axes;
%                     
%                 end
%                 
%                 
%                 %% PART 2: GRIDDED DATA
%                 
%                 % flag for existence of data in this line
%                 lineDataExist = isfield(fData,'X_1_2DgridHorizontalResolution');
%                 if lineDataExist
%                     
%                     % ID for gridded data
%                     lineDatID = [lineID '_dat'];
%                     
%                     % find gridded data object
%                     lineDatObj = findobj(app.Main_Axes,'Tag',lineDatID);
%                     
%                     % if it does not exist yet, create a dummy one with
%                     % 'Visible' set to 'off'
%                     if isempty(lineDatObj)
%                         lineDatObj = imagesc(app.Main_Axes,...
%                             fData.X_1E_2DgridEasting,...
%                             fData.X_N1_2DgridNorthing,...
%                             fData.X_NE_bs,...
%                             'Visible','off',...
%                             'Tag',lineDatID);
%                     end
%                     
%                     % then set data to display
%                     switch app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Value
%                         case '-'
%                             set(lineDatObj,'Visible','off');
%                         case 'Backscatter'
%                             data = fData.X_NE_bs;
%                             set(lineDatObj,'CData',data,...
%                                 'AlphaData',~isnan(data), ...
%                                 'Visible','on');
%                             % adjust colour scale
%                             colorbarOn = 1;
%                             cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
%                         case 'Bathymetry'
%                             data = fData.X_NE_bathy;
%                             set(lineDatObj,'CData',data,...
%                                 'AlphaData',~isnan(data), ...
%                                 'Visible','on');
%                             % adjust colour scale
%                             colorbarOn = 1;
%                             cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
%                         case 'Slope'
%                             data = fData.X_NE_slope;
%                             set(lineDatObj,'CData',data,...
%                                 'AlphaData',~isnan(data), ...
%                                 'Visible','on');
%                             % adjust colour scale
%                             colorbarOn = 1;
%                             cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
%                         case 'Mode'
%                             [~,gridPing] = ind2sub(size(fData.X8_BP_DepthZ),fData.X_NE_minIndexBP);
%                             indNan = isnan(gridPing);
%                             gridPing(indNan) = 1; %temporary
%                             data = app.FDataModeInd1P{ii}(gridPing);
%                             data(indNan) = NaN;
%                             set(lineDatObj,'CData',data,...
%                                 'AlphaData',~isnan(data), ...
%                                 'Visible','on');
%                             % adjust colour scale
%                             colorbarOn = 1;
%                             cax = [0.5 numel(app.FDataModeList)+0.5];
%                     end
%                     
%                     % push grid to the bottom of the display stack so
%                     % navigation stays on top
%                     ch = get(app.Main_Axes,'children'); % get all plots from app.Main_Axes
%                     [~,neworder] = sort(ismember(ch,lineDatObj)); % reorder the handles to move the background lines to the background
%                     set(app.Main_Axes,'children',ch(neworder)); % set the new order of all lines
%                     
%                     % Adjust zoom extents
%                     xlim(1) = nanmin(xlim(1),nanmin(fData.X_1E_2DgridEasting(:)));
%                     xlim(2) = nanmax(xlim(2),nanmax(fData.X_1E_2DgridEasting(:)));
%                     ylim(1) = nanmin(ylim(1),nanmin(fData.X_N1_2DgridNorthing(:)));
%                     ylim(2) = nanmax(ylim(2),nanmax(fData.X_N1_2DgridNorthing(:)));
%                     
%                 end
%                 
%                 
%                 %% PART 3: BAD DATA
%                 
%                 % flag for whether line has been analyzed
%                 lineAnalysisExist = isfield(fData,'X_BP_goodData');
%                 
%                 % analysis graphic objects
%                 lineBadDataID = [lineID '_bad'];
%                 lineBadDataObj = findobj(app.Main_Axes,'Tag',lineBadDataID);
%                 
%                 % to draw only if analysis data exist, but not objects yet
%                 if lineAnalysisExist && isempty(lineBadDataObj)
%                     % bad soundings
%                     idxBad = ~app.FData{ii}.X_BP_goodData;
%                     plot(app.Main_Axes,...
%                         app.FData{ii}.X_BP_bottomEasting(idxBad),...
%                         app.FData{ii}.X_BP_bottomNorthing(idxBad),...
%                         'Tag',lineBadDataID,...
%                         'Visible','on',...
%                         'LineStyle','none',...
%                         'Color','yellow',...
%                         'Marker','*');
%                     
%                     % bad pings
%                     idxBad = app.FData{ii}.X_1P_badPing;
%                     plot(app.Main_Axes,...
%                         app.FData{ii}.X_BP_bottomEasting(:,idxBad),...
%                         app.FData{ii}.X_BP_bottomNorthing(:,idxBad),...
%                         'Tag',lineBadDataID,...
%                         'Visible','on',...
%                         'LineStyle','none',...
%                         'Color','red',...
%                         'Marker','*');
%                 end
%                 
%             end
%             
%             % Set map view
%             set(app.Main_Axes,'YLim',ylim,'XLim',xlim);
%             app.equalizeMapView(1.2);
%             
%             % color scale adjust
%             if all(~isnan(cax))
%                 caxis(app.Main_Axes,cax);
%             end
%             
%             % set colorbar and colormap
%             if colorbarOn
%                 cb = colorbar(app.Main_Axes,'Location','east');
%                 
%                 % set data to display
%                 switch app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Value
%                     case 'Bathymetry'
%                         colormap(app.Main_Axes,jet);
%                     case 'Backscatter'
%                         colormap(app.Main_Axes,gray);
%                     case 'Slope'
%                         colormap(app.Main_Axes,gray);
%                     case 'Mode'
%                         if numel(app.FDataModeList)==1
%                             colormap(app.Main_Axes,[jet(1);jet(1)]);
%                         else
%                             colormap(app.Main_Axes,jet(numel(app.FDataModeList)));
%                         end
%                         set(cb,'Ticks',1:numel(app.FDataModeList),'ButtonDownFcn',@callbackColorbarButtonDown);
%                 end
%             end
%             
%             function callbackColorbarButtonDown(~,~)
%                 message = '';
%                 for kk = 1:numel(app.FDataModeList)
%                     message = [message, num2str(kk), ': ', app.FDataModeList{kk}, newline, newline];
%                 end
%                 message(end) = [];
%                 uialert(gcf,message,'Info',...
%                     'Icon','info');
%             end
%             
%         end
        
        % Adjust main axes Xlim and Ylim to ensure no stretch
        function equalizeMapView(app, bufferRatio)
            % This function adjusts the xlim and ylim properties of the map
            % to ensure the map maintains equal units of m/pixel in X and Y
            % so the data never looks stretched.
            % This function is to be called when any of those properties
            % are changed, whether automatically (e.g. when loading new
            % data) or interactively (e.g. using zoom functions).
            
            % these are the current extents of the data view, in
            % easting/northing (meters)
            xlim = get(app.Main_Axes,'XLim');
            ylim = get(app.Main_Axes,'YLim');
            
            % The adjustment depends on how the "aspect ratio" (ratio of
            % height to width) of the data view compare to that
            % of the map frame.
            % If the map frame is "flatter" than the data view, we will fit
            % the height of the data view to the map frame and then adjust
            % automatically in width.
            % If the data view is "flatter" than the map frame, we will fit
            % the width of the data view to the map frame and then adjust
            % automatically in height.
            
            % aspect ratio of the data view
            reqAspectRatio = diff(ylim)/diff(xlim);
            
            % aspect ratio of the map frame
            pos = getpixelposition(app.Main_Axes);
            mapAspectRatio = pos(4)/pos(3);
            
            % Instead of fitting the data view to the data frame exactly,
            % we allow in input some extra buffer. e.g. a value of 1.2
            % would mean that the frame width will represent 120% of the
            % data view width (if fitting in width). This way we "zoom-out"
            % a bit more compare to the original view. By default, we fit
            % exactly using a ratio of 1.
            if ~exist('bufferRatio','var')
                bufferRatio = 1;
            end
            
            if reqAspectRatio > mapAspectRatio
                % map frame is "flatter" than data view
                % -> Define ylim to fit the data in height
                ylim_new = [-diff(ylim),diff(ylim)]*bufferRatio/2 + ylim(1) + diff(ylim)/2;
                % X distance to ensure equal m per pixel in X and Y
                dx = diff(ylim_new)/mapAspectRatio;
                % define xlim with old view centeredd
                xlim_new = [-dx/2,dx/2] + xlim(1) + diff(xlim)/2;
            else
                % data view is "flatter" than map frame
                % -> Define xlim to fit the data in width
                xlim_new = [-diff(xlim),diff(xlim)]*bufferRatio/2 + xlim(1) + diff(xlim)/2;
                % Y distance to ensure equal m per pixel in X and Y
                dy = diff(xlim_new)*mapAspectRatio;
                % define ylim with old view centered
                ylim_new = [-dy/2,dy/2] + ylim(1) + diff(ylim)/2;
            end
            
            % set those new values to map
            set(app.Main_Axes,'YLim',ylim_new,'XLim',xlim_new);
            
        end
        
        function [cstModes,varModes] = transformModesList(app)
            
            nModes = numel(app.FDataModeList);
            if nModes ==1
                cstModes = app.FDataModeList{1};
                varModes = '';
                return
            end
            
            % turn list of modes from char to structs
            clear newModeStruct
            for ii = 1:nModes
                newModeStruct{ii} = struct();
                newLines = [0,strfind(app.FDataModeList{ii},newline),numel(app.FDataModeList{ii})+1];
                for jj = 1:numel(newLines)-1
                    newSetting = app.FDataModeList{ii}(newLines(jj)+1:newLines(jj+1)-1);
                    newPar = extractBefore(newSetting,':');
                    newVal = extractAfter(newSetting,': ');
                    newModeStruct{ii}.(newPar) = newVal;
                end
            end
            
            % for each parameter in the first mode, find if they are
            % constant in all other unique modes
            clear cstModesStruct
            firstModePars = fieldnames(newModeStruct{1});
            for jj = 1:numel(firstModePars)
                clear sameAsFirstMode
                for ii = 2:nModes
                    if isfield(newModeStruct{ii},firstModePars{jj}) && ...
                            strcmp(newModeStruct{ii}.(firstModePars{jj}),newModeStruct{1}.(firstModePars{jj}))
                        sameAsFirstMode(ii-1) = 1;
                    else
                        sameAsFirstMode(ii-1) = 0;
                    end
                end
                % if param is constant through all modes, move it
                if all(sameAsFirstMode)
                    % copy to constant modes
                    cstModesStruct.(firstModePars{jj}) = newModeStruct{1}.(firstModePars{jj});
                    % then remove from list of modes
                    for ii = 1:nModes
                        newModeStruct{ii} = rmfield(newModeStruct{ii},firstModePars{jj});
                    end
                end
            end
            
            % finally, turn the structs back into text
            
            % cst struct
            cstModesStructFields = fieldnames(cstModesStruct);
            clear cstModesStr
            for jj = 1:numel(cstModesStructFields)
                cstModesStr{jj} = [cstModesStructFields{jj}, ': ', cstModesStruct.(cstModesStructFields{jj})];
            end
            cstModes = strjoin(cstModesStr,newline);
            
            % var structs
            for ii = 1:nModes
                varModesStructFields = fieldnames(newModeStruct{ii});
                clear varModesStr
                for jj = 1:numel(varModesStructFields)
                    varModesStr{jj} = [varModesStructFields{jj}, ': ', newModeStruct{ii}.(varModesStructFields{jj})];
                end
                varModes{ii} = strjoin(varModesStr,newline);
            end
            
        end
        
        function updateAnalysisResultsLabel(app)
            
            % top text
            txt{1} = sprintf('%s v%s. Backscatter quality control results',app.Name,app.Version);
            txt{end+1} = sprintf('date: %s',datestr(now,'yyyy/mm/dd HH:MM:SS'));
            
            % modes
            nModes = numel(app.FDataModeList);
            txt{end+1} = '';
            if nModes == 1
                txt{end+1} = 'Dataset has a single mode. List of settings:';
                newlinesPos = [0,strfind(app.FDataModeList{1},newline),numel(app.FDataModeList{1})+1];
                for jj = 1:numel(newlinesPos)-1
                    txt{end+1} = sprintf(' * %s',app.FDataModeList{1}(newlinesPos(jj)+1:newlinesPos(jj+1)-1));
                end
            else
                [cstModes,varModes] = app.transformModesList();
                txt{end+1} = sprintf('Dataset has %i modes. List of settings that are CONSTANT for all modes:',nModes);
                newlinesPos = [0,strfind(cstModes,newline),numel(cstModes)+1];
                for jj = 1:numel(newlinesPos)-1
                    txt{end+1} = sprintf(' * %s',cstModes(newlinesPos(jj)+1:newlinesPos(jj+1)-1));
                end
                txt{end+1} = 'List of modes and their SPECIFIC settings:';
                for ii = 1:nModes
                    txt{end+1} = sprintf(' * Mode #%i/%i:',ii,nModes);
                    newlinesPos = [0,strfind(varModes{ii},newline),numel(varModes{ii})+1];
                    for jj = 1:numel(newlinesPos)-1
                        txt{end+1} = sprintf('   * %s',varModes{ii}(newlinesPos(jj)+1:newlinesPos(jj+1)-1));
                    end
                end
            end
             
            % lines loaded
            txt{end+1} = '';
            txt{end+1} = 'Line processing:';
            N = numel(app.FData);
            
            tot_nSoundings = 0;
            tot_nBadSoundings = 0;
            tot_nPings = 0;
            tot_nBadPings = 0;
            for ii = 1:N
                % file
                filename = CFF_file_name(app.FData{ii}.ALLfilename{1},1);
                txt{end+1} = sprintf('%i/%i: %s',ii,N,filename);
                
                % modes
                if numel(app.FDataModeList)>1 % do only if more than one mode in dataset
                    indModesInFile = unique(app.FDataModeInd1P{ii});
                    nModes = numel(indModesInFile);
                    modeTxt = cell(nModes,1);
                    for jj = 1:nModes
                        modeTxt{jj} = sprintf('%i',indModesInFile(jj));
                    end
                    txt{end+1} = sprintf('  * mode(s): %s', strjoin(modeTxt,','));
                end
                
                % line analysis results
                if isfield(app.FData{ii},'X_BP_goodData')
                    % number of beams and pings
                    [nBeams, nPings] = size(app.FData{ii}.X_BP_goodData);
                    nSoundings = nBeams.*nPings;
                    % number of bad pings and bad beams
                    nBadSoundings = sum(sum(~app.FData{ii}.X_BP_goodData));
                    nBadPings = sum(app.FData{ii}.X_1P_badPing);
                    % text
                    txt{end+1} = sprintf('  * bad soundings: %i/%i (%.2f%%)', nBadSoundings, nSoundings, nBadSoundings.*100./nSoundings);
                    txt{end+1} = sprintf('  * bad pings: %i/%i (%.2f%%)', nBadPings, nPings, nBadPings.*100./nPings);
                    % add to totals
                    tot_nSoundings = tot_nSoundings + nSoundings;
                    tot_nBadSoundings = tot_nBadSoundings + nBadSoundings;
                    tot_nPings = tot_nPings + nPings;
                    tot_nBadPings = tot_nBadPings + nBadPings;
                end
            end
            
            % total line stats
            if tot_nSoundings>0
                txt{end+1} = '';
                txt{end+1} = 'Total line processing:';
                txt{end+1} = sprintf('  * bad soundings: %i/%i (%.2f%%)', tot_nBadSoundings, tot_nSoundings, tot_nBadSoundings.*100./tot_nSoundings);
                txt{end+1} = sprintf('  * bad pings: %i/%i (%.2f%%)', tot_nBadPings, tot_nPings, tot_nBadPings.*100./tot_nPings);
            end
            
            % missing area in mosaic
            if ~isempty(app.mosaicInPolygon)
                txt{end+1} = '';
                txt{end+1} = 'Survey processing:';
                nTot = sum(app.mosaicInPolygon(:));
                nMiss = sum(app.mosaicMissing(:));
                txt{end+1} = sprintf('missing mosaic cells: %i/%i (%.2f%%)', nMiss, nTot, nMiss.*100./nTot);
            end
            
            % assessment result
            txt{end+1} = '';
            txt{end+1} = 'Assessment result: PASS/FAIL';
            
            % set text and scroll to bottom
            app.ProcessTab_AnalysisResults_TextArea.Value = txt;
            scroll(app.ProcessTab_AnalysisResults_TextArea, 'bottom');
            
            % desired output:
            % Iskaffe v0.0.0 - backscatter quality assessment
            % date: 2022/04/13 11:11:23
            %
            % Line processing results:
            % 1/N: filename
            %   * bad soundings: XXX/XXX (0.18%)
            %   * bad pings: XXX/XXX (0.18%)
            %   * Mode(s):
            %      * Mode blabla
            %      * Mode blabla
            % 2/N: filename
            %   * bad soundings: XXX/XXX (0.18%)
            %   * bad pings: XXX/XXX (0.18%)
            %   * Mode(s):
            %      * Mode blabla
            %      * Mode blabla2
            %
            % WARNING: More than one mode.
            %
            % Survey processing results:
            % Survey limits:
            %   9812,0992
            %   9812,0992
            %   9812,0992
            %   9812,0992
            % Grid size: 1 m
            % Missing mosaic cells: XXX/XXX (0.18%)
            %
            % Assessment result: PASS/FAIL
            
        end
        
        function cleanupMap(app)
            % This function deletes the graphic objects belonging to lines
            % that are no longer loaded
            
            % get all graphic objects in map
            allGraphObj = get(app.Main_Axes,'Children');
            % get ID of all loaded lines
            lineIDs = cellfun(@(x) num2str(x.ID,'%.0f'), app.FData,'UniformOutput',false);
            % delete obsolete graphic objects
            for ii = 1:numel(allGraphObj)
                graphObjTagLineID =  extract(allGraphObj(ii).Tag,digitsPattern);
                if ~ismember(graphObjTagLineID, lineIDs)
                    delete(allGraphObj(ii));
                end
            end
        end
        
        % Zoom out to include all object in map view, with buffer
        function zoomAllTheWayOut(app)
            
            % get all graphical objects
            h = allchild(app.Main_Axes);
            % initialize view limits
            xlim = [nan nan];
            ylim = [nan nan];
            % find view limits that include all objects
            for ii = 1:numel(h)
                if isprop(h(ii),'XData')
                    xlim(1) = min( xlim(1), min(h(ii).XData));
                    xlim(2) = max( xlim(2), max(h(ii).XData));
                    ylim(1) = min( ylim(1), min(h(ii).YData));
                    ylim(2) = max( ylim(2), max(h(ii).YData));
                elseif isprop(h(ii),'Position')
                    xlim(1) = min( xlim(1), min(h(ii).Position(:,1)));
                    xlim(2) = max( xlim(2), max(h(ii).Position(:,1)));
                    ylim(1) = min( ylim(1), min(h(ii).Position(:,2)));
                    ylim(2) = max( ylim(2), max(h(ii).Position(:,2)));
                end
            end
            % default limits where there are no objects in the map
            if any(isnan([xlim,ylim]))
                xlim = [0 1];
                ylim = [0 1];
            end
            % Set map view
            set(app.Main_Axes,'YLim',ylim,'XLim',xlim);
            % Equalize map view, with a buffer
            app.equalizeMapView(1.2);
        end
        
        function updateButtons(app)
            % enable or disable buttons depending on what has been loaded
            
            %% Line processing panel
            
            % "Grid" and "Analyze" are only enabled if lines are loaded
            if ~isempty(app.FData)
                app.ProcessTab_Grid_Button.Enable = 'on';
                app.ProcessTab_Analyze_Button.Enable = 'on';
            else
                app.ProcessTab_Grid_Button.Enable = 'off';
                app.ProcessTab_Analyze_Button.Enable = 'off';
            end
            
            % "Gridded data displayed" is only enabled if at least one line
            % has been gridded
            if ~isempty(app.FData) && any(cellfun(@(s) isfield(s,'X_1_2DgridHorizontalResolution'), app.FData))
                app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Enable = 'on';
            else
                app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Enable = 'off';
            end
            
            % "Show line analysis" is only enabled if at least one
            % line has been analyzed
            if ~isempty(app.FData) && any(cellfun(@(s) isfield(s,'X_BP_goodData'), app.FData))
                app.ProcessTab_ShowLineAnalysis_CheckBox.Enable = 'on';
            else
                app.ProcessTab_ShowLineAnalysis_CheckBox.Enable = 'off';
            end
            
            %% Survey processing panel
            
            % "File" and "Draw" are always enabled
            
            % "Edit" and "Clear" are only enabled if there is a ROI polygon
            % loaded
            if ~isempty(findobj('Tag','ROI'))
                app.ProcessTab_Edit_Button.Enable = 'on';
                app.ProcessTab_Clear_Button.Enable = 'on';
            else
                app.ProcessTab_Edit_Button.Enable = 'off';
                app.ProcessTab_Clear_Button.Enable = 'off';
            end
            
            % "Mosaic" is only enabled if there is a ROI polygon loaded and
            % if at least one line has been gridded and analyzed
            if ~isempty(findobj('Tag','ROI')) && ...
                    ~isempty(app.FData) &&  ...
                    any(cellfun(@(s) all(isfield(s,{'X_1_2DgridHorizontalResolution','X_BP_goodData'})), app.FData))
            app.ProcessTab_AnalyzeSurvey_Button.Enable = 'on';
            else
                app.ProcessTab_AnalyzeSurvey_Button.Enable = 'off';
            end
            
            % "Show survey analysis" and "Export survey analysis" are only
            % enabled if survey has been analysed
            if ~isempty(app.mosaicMissing)
                app.ProcessTab_ShowResults_CheckBox.Enable = 'on';
                app.ProcessTab_ExportSurveyAnalysis_Button.Enable = 'on';
            else
                app.ProcessTab_ShowResults_CheckBox.Enable = 'off';
                app.ProcessTab_ExportSurveyAnalysis_Button.Enable = 'off';
            end
            
        end
        
        function deleteROIpolygon(app)
            % delete ROI polygon
            delete(findobj('Tag','ROI'));
        end
        
        function createROIpolygon(app, pos)
            % create ROI polygon
            % start with clearing any existing polygon
            app.deleteROIpolygon();
            if exist('pos','var')
                % create ROI from positions
                images.roi.Polygon( app.Main_Axes,...
                    'Position', pos, ...
                    'FaceAlpha', 0, ...
                    'InteractionsAllowed','none', ...
                    'Tag','ROI');
            else
                % start ROI drawing mode
                h = drawpolygon(app.Main_Axes,...
                    'FaceAlpha', 0.2, ...
                    'Tag','ROI');
                % when done, remove interactions and facealpha
                h.FaceAlpha = 0;
                h.InteractionsAllowed = 'none';
            end
        end
        
        function updateListOfModes(app)
            
            % reset the variables holding information on modes
            app.FDataModeList = {}; % list of all modes in loaded data
            app.FDataModeInd1P = cell(size(app.FData)); % index in mode list for each ping of each line
            
            % operate line by line
            for ii = 1:numel(app.FData)
                % get line fdata
                fData = app.FData{ii};
                % list of runtime mode parameters in this line
                fields = fieldnames(fData);
                fields = fields(startsWith(fields,'Ru_1D'));
                fields = setdiff(fields,{'Ru_1D_Date','Ru_1D_TimeSinceMidnightInMilliseconds','Ru_1D_PingCounter'});
                fields = sort(fields);
                nMdFld = numel(fields);
                % for each runtime datagram, combine all mode parameters
                % and values into a single text entry
                nRuDtg = numel(fData.Ru_1D_Date);
                Ru_1D_mode_text = cell(1,nRuDtg);
                for jj = 1:nRuDtg
                    for kk = 1:nMdFld
                        val = fData.(fields{kk})(jj);
                        if isnumeric(val)
                            val = num2str(val);
                        elseif iscategorical(val)
                            val = char(val);
                        end
                        newModeEntry = [erase(fields{kk},'Ru_1D_'), ': ', val];
                        if kk==1
                            Ru_1D_mode_text{jj} = newModeEntry;
                        else
                            Ru_1D_mode_text{jj} = strjoin({Ru_1D_mode_text{jj},newModeEntry},newline);
                        end
                    end
                end
                % matching ping counters between the Runtime datagrams and
                % the X8 datagrams, get the mode of each ping
                nPings = numel(fData.X8_1P_Date);
                X8_1P_ind_mode = ones(1,nPings);
                for jj = 1:nRuDtg
                    idx = fData.X8_1P_PingCounter>=fData.Ru_1D_PingCounter(jj);
                    X8_1P_ind_mode(idx) = jj;
                end
                % find the modes in this line that did not exist so far,
                % and them to the list
                uniqueModesInLine = unique(Ru_1D_mode_text,'stable');
                for jj = 1:numel(uniqueModesInLine)
                    if ~ismember(uniqueModesInLine{jj},app.FDataModeList)
                        app.FDataModeList{end+1} = uniqueModesInLine{jj};
                    end
                end
                % find the index of each ping in the mode list
                [~,Locb] = ismember(Ru_1D_mode_text,app.FDataModeList);
                app.FDataModeInd1P{ii} = Locb(X8_1P_ind_mode);
            end
            
            % if more that one mode is detected
            if numel(app.FDataModeList)>1
                % switch display to mode (won't do anything if lines are
                % not loaded)
                app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Value = 'Mode';
                app.updateMap();
                app.zoomAllTheWayOut();
                % throw warning
                uialert(app.MainFigure,'Different modes were detected in the set of loaded lines','Multiple modes detected');
            end
            
            % export mode change narrative
            %             loadedLinesFirstPingSDN = cellfun(@(x) x.X_1P_pingSDN(1), app.FData,'UniformOutput',true);
            %             [~,I] = sort(loadedLinesFirstPingSDN,'ascend');
            %             for ii = 1:numel(app.FData)
            %                 % get line fdata in chronological order
            %                 fData = app.FData{I(ii)};
            %                 print()
            %
            %             end

        end
        
        function createLineNavigation(app, lineIndex, objID)
            % create the navigation track of requested line
            
            % get data
            fData = app.FData{lineIndex};
            
            % nav line color
            nav_col = [0 0 0]; % black
            
            % 1. draw main navigation line
            plot(app.Main_Axes,fData.X_1P_pingE,fData.X_1P_pingN,...
                'Visible','on','Color',nav_col,'Tag',objID);
            
            % 2. draw points every dt seconds as subsampled navigation
            dt = 60; % in seconds
            pingTimeSec = 0.001.*(fData.X_1P_pingTSMIM-fData.X_1P_pingTSMIM(1));
            idx_f = mod(ceil(pingTimeSec),dt)==0 & [0,diff(ceil(pingTimeSec))];
            
            plot(app.Main_Axes,fData.X_1P_pingE(idx_f),fData.X_1P_pingN(idx_f),'.',...
                'Visible','on','Color',nav_col,'Tag',objID);
            
            % 3. start and end symbols
            % OLD WAY
            % % draw circle as start of line and square at end
            % plot(app.Main_Axes,fData.X_1P_pingE(1),fData.X_1P_pingN(1),'o',...
            %    'Visible','on','Color',nav_col,'Tag',objID);
            % plot(app.Main_Axes,fData.X_1P_pingE(end),fData.X_1P_pingN(end),'s',...
            %    'Visible','on','Color',nav_col,'Tag',objID);
            
            nPings = numel(fData.X_1P_pingE);
            iPingDir = min(5,nPings); % number of pings to use to establish heading for start/end symbols
            
            % at start of line, draw a triangle
            
            % set triangle side length to half the depth
            L = 0.5*nanmean(fData.X8_BP_DepthZ(:,1)); % in m
            
            % start heading in deg
            h = atan2d( ...
                (fData.X_1P_pingE(iPingDir)-fData.X_1P_pingE(1)), ...
                (fData.X_1P_pingN(iPingDir)-fData.X_1P_pingN(1)) );
            
            % X and Y positions of triangle vertices
            lineStartX = fData.X_1P_pingE(1) + [-0.5*L*cosd(h),0.5*L*cosd(h),0.5*L*sind(h),-0.5*L*cosd(h)];
            lineStartY = fData.X_1P_pingN(1) + [0.5*L*sind(h),-0.5*L*sind(h),0.5*L*cosd(h),0.5*L*sind(h)];
            
            % draw
            plot(app.Main_Axes,lineStartX,lineStartY,...
                'Visible','on','Color',nav_col,'Tag',objID);
            
            % at end of line, draw a segment
            
            % set segment length to half the depth
            L = 0.5*nanmean(fData.X8_BP_DepthZ(:,end));
            
            % end heading in deg
            h = atan2d( ...
                (fData.X_1P_pingE(end)-fData.X_1P_pingE(end-iPingDir+1)), ...
                (fData.X_1P_pingN(end)-fData.X_1P_pingN(end-iPingDir+1)) );
            
            % X and Y positions of segment vertices
            lineEndX = fData.X_1P_pingE(end) + [-0.5*L*cosd(h),0.5*L*cosd(h)];
            lineEndY = fData.X_1P_pingN(end) + [0.5*L*sind(h),-0.5*L*sind(h)];
            
            % draw
            plot(app.Main_Axes,lineEndX,lineEndY,...
                'Visible','on','Color',nav_col,'Tag',objID);
            
            % add click-on-object callback to all those line navigation
            % elements
            lineNavObj = findobj(app.Main_Axes,'Tag',objID);
            for jj = 1:numel(lineNavObj)
                lineNavObj(jj).ButtonDownFcn = createCallbackFcn(app, @MapObject_ButtonDown, true);
            end
            
        end
        
        function createLineCoverage(app, lineIndex, objID)
            % create the swath coverage of requested line
            
            % get data
            fData = app.FData{lineIndex};
            
            % get coverage polygon
            Nstart = 20;
            pgon = CFF_get_swath_coverage(fData,Nstart);
            
            % display on map
            plot(app.Main_Axes,pgon,'Visible','on','Tag',objID);
            
        end
        
        function createLineGriddedData(app, lineIndex, objID)
            % create the gridded data of requested line
            
            % get data
            fData = app.FData{lineIndex};
            
            % exit if fData does not have gridded data
            if ~isfield(fData,'X_1_2DgridHorizontalResolution')
                return
            end
            
            % create a dummy one with 'Visible' set to 'off'
            imagesc(app.Main_Axes,...
                fData.X_1E_2DgridEasting,...
                fData.X_N1_2DgridNorthing,...
                zeros(numel(fData.X_N1_2DgridNorthing),numel(fData.X_1E_2DgridEasting)),...
                'Visible','off',...
                'Tag',objID);
            
        end
        
        function createLineBadData(app, lineIndex, objID)
            % create the bad data of requested line
            
            % get data
            fData = app.FData{lineIndex};
            
            % exit if fData does not have gridded data
            if ~isfield(fData,'X_BP_goodData')
                return
            end
            
            % bad soundings
            idxBad = ~fData.X_BP_goodData;
            plot(app.Main_Axes,...
                fData.X_BP_bottomEasting(idxBad),...
                fData.X_BP_bottomNorthing(idxBad),...
                'Tag',objID,...
                'Visible','on',...
                'LineStyle','none',...
                'Color','yellow',...
                'Marker','*');
            
            % bad pings
            idxBad = fData.X_1P_badPing;
            plot(app.Main_Axes,...
                fData.X_BP_bottomEasting(:,idxBad),...
                fData.X_BP_bottomNorthing(:,idxBad),...
                'Tag',objID,...
                'Visible','on',...
                'LineStyle','none',...
                'Color','red',...
                'Marker','*');
            
        end
        
        function updateLineGriddedDataDisplayed(app)
            
            % get gridded data objects IDs
            datObj = findobj('-regexp','Tag','.*_dat');
            if isempty(datObj)
                % not gridded data object in app
                % Make sure the gridded data displayed is '-' and exit
                app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Value = '-';
                return
            end
            nObj = numel(datObj);
            
            % find indices of corresponding fData
            linesID = cellfun(@(f) num2str(f.ID,'%.0f'),app.FData,'UniformOutput',false);
            datObjIndex = nan(nObj,1);
            for ii = 1:nObj
                datObjIndex(ii) = find(strcmp(linesID,datObj(ii).Tag(1:end-4)));
            end
            
            % then set data to display
            switch app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Value
                case {'Backscatter','Bathymetry','Slope'}
                    % we group all three cases here because their
                    % processing is the same kind, apart from the selection
                    % of the data type itself and the colormap
                    switch app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Value
                        case 'Backscatter'
                            field = 'X_NE_bs';
                            cmap = gray;
                        case 'Bathymetry'
                            field = 'X_NE_bathy';
                            cmap = jet;
                        case 'Slope'
                            field = 'X_NE_slope';
                            cmap = autumn;
                    end
                    % set colorbar and colormap
                    cb = colorbar(app.Main_Axes,'Location','east');
                    colormap(app.Main_Axes,cmap);
                    % init cax limits
                    cax  = [nan nan];
                    % then work for each gridded data object
                    for ii = 1:nObj
                        % get data
                        data = app.FData{datObjIndex(ii)}.(field);
                        % update CData in graph obj
                        set(datObj(ii),'CData',data,...
                            'AlphaData',~isnan(data), ...
                            'Visible','on');
                        % adjust colour scale
                        cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
                        % ensure corresponding coverage object is invisible
                        covObjTag = strrep(datObj(ii).Tag,'dat','cov');
                        covObj = findobj('-regexp','Tag',covObjTag);
                        set(covObj,'Visible','off');
                    end
                    % color scale adjust
                    caxis(app.Main_Axes,cax);
                    
                case '-'
                    for ii = 1:nObj
                        % turn the data object invisible
                        set(datObj(ii),'Visible','off');
                        % turn the corresponding coverage object visible
                        covObjTag = strrep(datObj(ii).Tag,'dat','cov');
                        covObj = findobj('-regexp','Tag',covObjTag);
                        set(covObj,'Visible','on');
                    end
                    % remove colorbar
                    colorbar(app.Main_Axes,'off');
                    
                case 'Mode'
                    
                    % set colorbar and colormap
                    cb = colorbar(app.Main_Axes,'Location','east');
                    if numel(app.FDataModeList)==1
                        colormap(app.Main_Axes,[jet(1);jet(1)]);
                    else
                        colormap(app.Main_Axes,jet(numel(app.FDataModeList)));
                    end
                    set(cb,'Ticks',1:numel(app.FDataModeList),'ButtonDownFcn',@callbackColorbarButtonDown);
                    caxis(app.Main_Axes,[0.5 numel(app.FDataModeList)+0.5]);
                    
                    for ii = 1:nObj
                        % get fData
                        fData = app.FData{datObjIndex(ii)};
                        [~,gridPing] = ind2sub(size(fData.X8_BP_DepthZ),fData.X_NE_minIndexBP);
                        indNan = isnan(gridPing);
                        gridPing(indNan) = 1; %temporary
                        data = app.FDataModeInd1P{datObjIndex(ii)}(gridPing);
                        data(indNan) = NaN;
                        set(datObj(ii),'CData',data,...
                            'AlphaData',~isnan(data), ...
                            'Visible','on');
                        % ensure corresponding coverage object is invisible
                        covObjTag = strrep(datObj(ii).Tag,'dat','cov');
                        covObj = findobj('-regexp','Tag',covObjTag);
                        set(covObj,'Visible','off');
                    end
                    
            end
            
            function callbackColorbarButtonDown(~,~)
                nModes = numel(app.FDataModeList);
                clear txt
                if nModes == 1
                    txt{1} = 'Dataset has a single mode. List of settings:';
                    newlinesPos = [0,strfind(app.FDataModeList{1},newline),numel(app.FDataModeList{1})+1];
                    for jj = 1:numel(newlinesPos)-1
                        txt{end+1} = sprintf(' * %s',app.FDataModeList{1}(newlinesPos(jj)+1:newlinesPos(jj+1)-1));
                    end
                else
                    [cstModes,varModes] = app.transformModesList();
                    txt{1} = sprintf('Dataset has %i modes. List of settings that are CONSTANT for all modes:',nModes);
                    newlinesPos = [0,strfind(cstModes,newline),numel(cstModes)+1];
                    for jj = 1:numel(newlinesPos)-1
                        txt{end+1} = sprintf(' * %s',cstModes(newlinesPos(jj)+1:newlinesPos(jj+1)-1));
                    end
                    txt{end+1} = 'List of modes and their SPECIFIC settings:';
                    for ii = 1:nModes
                        txt{end+1} = sprintf(' * Mode #%i/%i:',ii,nModes);
                        newlinesPos = [0,strfind(varModes{ii},newline),numel(varModes{ii})+1];
                        for jj = 1:numel(newlinesPos)-1
                            txt{end+1} = sprintf('   * %s',varModes{ii}(newlinesPos(jj)+1:newlinesPos(jj+1)-1));
                        end
                    end
                end
                
                message = strjoin(txt,newline);
                uialert(gcf,message,'Acquisition modes',...
                    'Icon','info');

            end
            
        end
        
        function reorderLinesGraphObjects(app)
            % graphic objects are drawn in an axes overlaying one another.
            % The stack order is determined by their index in the array of
            % children objects. Object with index #1 is visible ontop off
            % all others (foreground). Index 'end' is at bottom of the
            % stack (background). We want the stack order to reflect the
            % list of lines loaded (as displayed in the Loaded Lines tab),
            % and the objects for each line to be in a specific order from
            % foreground to background: navigation (line and line
            % start/end) > bad data > gridded data > coverage polygon.
            
            % get tags of all graphic objects in map, in current stack
            % order.
            allObj = get(app.Main_Axes,'Children');
            allTags = arrayfun(@(obj) get(obj,'Tag'),allObj,'UniformOutput',false);
            
            % ok so it's a bit ugly, but we're going to temporary change
            % the 'ROI' tag (if there's one) to '999_ROI' so that the
            % reordering algorithm can work with it.
            allTags(strcmp(allTags,'ROI')) = {'999_ROI'};
            
            % IDs of all loaded lines, in desired stack order
            ordered_linesID = cellfun(@(f) num2str(f.ID,'%.0f'),app.FData,'UniformOutput',false);
            
            % tags of each object type, in desired stack order
            ordered_objType = {'nav','bad','dat','cov'};
            nTypes = numel(ordered_objType);
            
            % create the reordering index
            [~,Locb1] = ismember(extract(allTags,digitsPattern),ordered_linesID);
            [~,Locb2] = ismember(extract(allTags,lettersPattern),ordered_objType);
            [~,neworder] = sort(Locb1*(nTypes+1) + Locb2);
            
            % reorder objects stack
            allObjOrdered = allObj(neworder);
            allTagsOrdered = arrayfun(@(obj) get(obj,'Tag'),allObjOrdered,'UniformOutput',false);
            
            % save reordered stack of objects back on main axes
            set(app.Main_Axes,'children',allObjOrdered);
            
        end
     
        % prompt user for a folder containing files for batch process and
        % return this list
        function [filesList, folder_init] = promptForInputFiles(app, titlePurpose, filesType)

            % prompt for input folder of raw data files
            currentFolder = app.FilesTab_Folder_EditField.Value;
            msg = sprintf('Select a folder of raw data files for %s',lower(titlePurpose));
            folder_init = uigetdir(currentFolder,msg);
            figure(app.MainFigure); % uigetdir sometimes puts app in the background. Bring it back on top
            
            % if cancelling the dialog box, exit here
            if folder_init == 0
                filesList = [];
                return
            end
            
            % list raw files 1) only in search folder, and 2) recursively
            recursive_flag = 0;
            rawDataFilesListRoot = CFF_list_raw_files_in_dir(folder_init,recursive_flag,filesType);
            recursive_flag = 1;
            rawDataFilesListRecu = CFF_list_raw_files_in_dir(folder_init,recursive_flag,filesType);
            
            % check if files lists are different
            if isequal(rawDataFilesListRoot,rawDataFilesListRecu)
                % no difference, proceed
                filesList = rawDataFilesListRoot;
            else
                % files list are different, prompt user for which they want
                msg = sprintf('There are %i files in the specified folder, but %i files in total when including sub-folders. Do you want to include files in sub-folders?',numel(rawDataFilesListRoot),numel(rawDataFilesListRecu));
                selection = uiconfirm(app.MainFigure,msg,titlePurpose,...
                    'Options',{'Folder only', 'Include sub-folders','Cancel'},...
                    'DefaultOption',2, 'CancelOption',3);
                switch selection
                    case 'Folder only'
                        filesList = rawDataFilesListRoot;
                    case 'Include sub-folders'
                        filesList = rawDataFilesListRecu;
                    case 'Cancel'
                        filesList = [];
                        return
                end
            end
 
        end
        
        function exportFile = promptForExportFile(app, titlePurpose, defaultFileName, defaultFileExt)
            
            % prompt for output file of results to export
            defaultExportFolder = fullfile(app.UserFolder,'exports');
            if ~isfolder(defaultExportFolder)
                mkdir(defaultExportFolder);
            end
            defaultExportFilename = sprintf('%s_%s.%s',datestr(now,'yyyymmddTHHMMSS'),defaultFileName,defaultFileExt);
            defaultExportFile = fullfile(defaultExportFolder,defaultExportFilename);
            filter = sprintf('*.%s',defaultFileExt);
            title = sprintf('Output file for %s results',lower(titlePurpose));
            [file,path] =  uiputfile(filter,title,defaultExportFile);
            figure(app.MainFigure); % uiputfile sometimes puts app in the background. Bring it back on top
            
            % if cancelling the dialog box, exit here
            if file == 0
                exportFile = '';
                return
            else
                exportFile = fullfile(path, file);
            end
            
        end
        
        function dualprint(app,fid,varargin)
            fprintf(fid,varargin{:}); % print to text file
            fprintf(varargin{:}); % print to screen
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %% 1. Initialization
            
            % Start time
            startTime = now;
            
            % Set (and create if needed) the app user folder
            app.UserFolder = regexprep(userpath,'MATLAB',app.Name);
            if ~isfolder(app.UserFolder)
                mkdir(app.UserFolder);
            end
            
            % Start diary
            diary('off');
            app.DiaryFile = fullfile(app.UserFolder, sprintf('%s_%s.log',datestr(startTime,'yyyymmddTHHMMSS'),app.Name));
            diary(app.DiaryFile);
            diary('on');
            
            % Start messages
            fprintf('%s - Starting %s v%s (powered by CoFFee v%s). Please wait...\n',datestr(startTime),app.Name,app.Version,app.CoffeeVersion);
            fprintf('Find a log of this output at %s.\n',app.DiaryFile)
            

            %% 2. System files and folders
            
            % If on MATLAB, start with adding the app's root folder (and
            % its subfolders) to the path
            app.RootFolder = fileparts(mfilename('fullpath'));
            if ~isdeployed
                addpath(genpath(app.RootFolder));
            end
            
            % Init config file if needed
            app.ConfigFile = fullfile(app.UserFolder,'config.json');
            if ~isfile(app.ConfigFile)
                init_config_file(app);
            end
            
            % If on MATLAB, need to find and add CoFFee to the path
            if ~isdeployed
                % check coffee folder
                coffeeFolder = get_config_field(app,'coffeeFolder');
                if ~is_coffee_folder(app,coffeeFolder)
                    % throw an alert to find manually a suitable coffee folder
                    coffeeFolder = uigetdir(app.RootFolder,'Select suitable CoFFee folder');
                    figure(app.MainFigure); % uigetdir sometimes puts app in the background. Bring it back on top
                    % second check
                    if ~is_coffee_folder(app,coffeeFolder)
                        error('Not a CoFFee folder.');
                    end
                end
                % check coffee version is the one we need
                isVersionOK = is_coffee_version(app,coffeeFolder,app.CoffeeVersion);
                if ~isVersionOK
                    warning(['This version of %s (%s) was built with a version'...
                        ' of CoFFee (%s) that is different from that (%s) of the toolbox'...
                        ' you have specified (%s). Proceeding, but you may experience'...
                        ' issues. Consider checking out the correct CoFFee version.'],...
                        app.Name,app.Version,app.CoffeeVersion,get_coffee_version(app,coffeeFolder),coffeeFolder);
                end
                % add coffee to path
                addpath(genpath(coffeeFolder));
                % save that path in config file
                set_config_field(app,'coffeeFolder',coffeeFolder);
            end
            
            % Record fData format version
            app.FDataVersion = CFF_get_current_fData_version();
            
            
            %% 3. Visual setup
            
            % Make figure invisible while we finish the visual setup
            if isdeployed()
                app.MainFigure.Visible = 'off';
            end
            
            % Main figure icon and name
            app.MainFigure.Icon = 'figure_icon.png';
            app.MainFigure.Name = sprintf('%s v%s',app.Name,app.Version);
            
            % Resize and reposition figure according to monitor's dimension
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            app.MainFigure.Position = [left bottom width height];
            
            % Set default folder location
            startDataPath = app.get_config_field('lastDataPath');
            if isempty(startDataPath)
                startDataPath = app.UserFolder;
            end
            app.FilesTab_Folder_EditField.Value = startDataPath;
            
            % Initialize buttons enable/disable state
            app.updateButtons();
            
            
            %% 4. Non-visual setup
            
            % Make figure visible now
            app.MainFigure.Visible = 'on';
            
            % Link main axes and survey result axes
            linkaxes([app.Main_Axes, app.Survey_Axes]);
            
            % Next section is setting up map interactions. It gets
            % complicated.
            % Ideally I want to have:
            % 1. zooming and panning as built-in interactions, resp using
            % the scroll wheel and left-click-and-dragging.
            % 2. Toolbar interactions 'export', 'pan', 'zoomin', 'zoomout',
            % and 'restoreview'
            % 3. Note I need to maintain the figure position and equalized
            % axes after any zoom
            % 4. Some customized interactions: getting coordinates (Easting
            % Northing) of the current mouse pointer and information from
            % an object when clicked on (e.g. file name).
            %
            % For now I have no figured how to make #1 work with the rest
            % so I am disabling the legacy built-in interactions, but
            % ideally have it work too.
            
            % 1. Customize the maps' available built-in interactions (i.e.
            % those available when NOT using the map's toolbar buttons)
            % enableDefaultInteractivity(app.Main_Axes);
            % app.Main_Axes.Interactions = [panInteraction zoomInteraction];
            
            % 2. Custom map toolbar
            enableLegacyExplorationModes(app.MainFigure);
            axToolstrip = axtoolbar(app.Main_Axes,{'export','pan','zoomin','zoomout','restoreview'});
            
            % 3. Post-zoom callback to equalize map axes
            h = zoom(app.MainFigure);
            h.ActionPostCallback = @(o,e) app.equalizeMapView();
            
            % 4. Customize "Restore View" to simply execute "Zoom all the way out"
            indRestoreButton = strcmpi({axToolstrip.Children.Icon}, 'restoreview');
            restoreButtonHandle = axToolstrip.Children(indRestoreButton);
            restoreButtonHandle.ButtonPushedFcn = @(~,~) zoomAllTheWayOut(app);
            
            % 5. callback when clicking on the map
            app.Main_Axes.ButtonDownFcn = createCallbackFcn(app, @MapObject_ButtonDown, true);
            
            % 6. Callback when moving the pointer on the figure (can't have
            % such callback on the map alone)
            app.MainFigure.WindowButtonMotionFcn = createCallbackFcn(app, @MainFigure_WindowButtonMotion, true);
            
            % Initialize Raw Files table
            app.updateRawFilesTable();
            
            
            %% 5. Finalize start-up
            fprintf('Ready.\n');
            
        end

        % Menu selected function: ParametersanalysisKoppMenu
        function Menu_Tools_BatchProc_ParamAna_Selected(app, event)
            % moving Kopp here...
            % Run the parameter analysis on a bunch of files without saving
            % the converted files on the hard-drive and without loading
            % them in Iskaffe. This way, no limitation on number of files
            % to run the analysis on. 
            
            % prompt for input folder of raw data files to process
            titleBatchProc = 'Batch Parameter Analysis (Kopp)';
            [filesList, folder] = app.promptForInputFiles(titleBatchProc,{'.all','.kmall'});
            
            % check number of files, and prompt for confirmation
            nFiles = numel(filesList);
            if nFiles == 0
                msg = 'No raw data files found. Processing cancelled';
                uialert(app.MainFigure,msg,titleBatchProc);
                fprintf('%s cancelled.', titleBatchProc);
                return
            else
                msg = sprintf('%i files will be analysed. This could take a while. Confirm?',nFiles);
                selection = uiconfirm(app.MainFigure,msg,titleBatchProc);
                switch selection
                    case 'Cancel'
                        fprintf('%s cancelled.', titleBatchProc);
                        return
                end
            end
            
            % prompt for output file of results to export
            exportFile = promptForExportFile(app, titleBatchProc, 'batch_parameter_analysis', 'txt');
          
            % if cancelling the dialog box, exit here
            if isempty(exportFile)
                fprintf('%s cancelled.', titleBatchProc);
                return
            end
            
            % open export file
            fid = fopen(exportFile,'wt');

            % comms
            app.dualprint(fid,'Analysing contents of folder %s...\n',folder);
            app.dualprint(fid,'Found %i files.\n',nFiles);
            app.dualprint(fid,'\nREADING FILES:\n')
            
            % start progress dialog
            progDlg = uiprogressdlg(app.MainFigure,...
                'Title',titleBatchProc,...
                'Cancelable','on');
            
            % initialize runtime params struct
            runtimeParams = struct('file',[],'time',[],'params',[]);
            dd = 0;
           
            % for each file
            for ii = 1:nFiles
                
                % Check for Cancel button press
                if progDlg.CancelRequested
                    break
                end
                
                % comms
                progDlg.Value = ii./nFiles;
                progDlg.Message = sprintf('Reading file %i/%i',ii,nFiles);
                
                % get file name
                rawFile = filesList{ii};
                if iscell(rawFile)
                    % pair of .all/.wcd
                    rawFile = CFF_onerawfileonly(rawFile);
                    rawFile = rawFile{1};
                end
                
                switch CFF_file_extension(rawFile)
                    
                    case '.all'
                        % parse file for runtime parameters
                        ALLdata = CFF_read_all(rawFile,82);
                        % decode runtime parameters
                        runtimeData = CFF_decode_RuntimeParameters(ALLdata.EM_Runtime);
                        runtimeFields = fieldnames(runtimeData);
                        nDatags = numel(ALLdata.EM_Runtime.PingCounter);
                        % for each datagram
                        for jj = 1:nDatags
                            % increase counter for saving records
                            dd = dd+1;
                            % add filename and datagram time to the struct
                            runtimeParams(dd).file = ALLdata.ALLfilename;
                            runtimeParams(dd).time = CFF_all_time_to_datetime(ALLdata.EM_Runtime.Date(jj),ALLdata.EM_Runtime.TimeSinceMidnightInMilliseconds(jj));
                            % then add each parameter, as a string
                            for kk = 1:numel(runtimeFields)
                                par = runtimeFields{kk};
                                val = runtimeData.(par)(jj);
                                if isnumeric(val)
                                    val = num2str(val);
                                elseif iscategorical(val)
                                    val = char(val);
                                end
                                runtimeParams(dd).params.(par) = val;
                            end
                        end
       
                    case '.kmall'
                        % parse file for runtime parameters
                        KMALLdata = CFF_read_kmall(rawFile,'#IOP');
                        % decode runtime parameters
                        runtimeData = CFF_decode_IOP(KMALLdata.EMdgmIOP);
                        runtimeFields = fieldnames(runtimeData);
                        nDatags = numel(KMALLdata.EMdgmIOP);
                        % for each datagram
                        for jj = 1:nDatags
                            % increase counter for saving records
                            dd = dd+1;
                            % add filename and datagram time to the struct
                            runtimeParams(dd).file = KMALLdata.KMALLfilename;
                            runtimeParams(dd).time = CFF_kmall_time_to_datetime(KMALLdata.EMdgmIOP(jj).header.time_sec,...
                                KMALLdata.EMdgmIOP(jj).header.time_nanosec);
                            % then add each parameter, as a string
                            for kk = 1:numel(runtimeFields)
                                par = runtimeFields{kk};
                                val = runtimeData.(par)(jj);
                                if isnumeric(val)
                                    val = num2str(val);
                                elseif iscategorical(val)
                                    val = char(val);
                                end
                                runtimeParams(dd).params.(par) = val;
                            end
                        end
                end
                
                % display
                app.dualprint(fid,'File %i/%i (%s): Found %i runtime parameters datagrams\n',ii,nFiles,rawFile,nDatags);
                
            end
                     
            progDlg.Message = sprintf('Compiling results');
            
            % sort records chronologically (in case file names were not
            % accurately named by time)
            [~,indf] = sort([runtimeParams.time]','ascend');
            runtimeParams = runtimeParams(indf);
            nDatags = numel(runtimeParams);
            
            app.dualprint(fid,'\nCHRONOLOGICAL CHANGES:\n')
            
            % disp all params from first datagram in first file
            app.dualprint(fid,'%s first file started (%s)\n',datestr(runtimeParams(1).time,'dd-mmm-yyyy HH:MM:SS.FFF'),runtimeParams(1).file);
            app.dualprint(fid,'   Initial parameters:\n');
            fields = fieldnames(runtimeParams(1).params);
            for kk = 1:numel(fields)
                app.dualprint(fid,'   * %s: %s\n',fields{kk}, runtimeParams(1).params.(fields{kk}));
            end
            
            % then go through each record
            for ii = 2:nDatags
                % check if new file
                if ~strcmp(runtimeParams(ii).file,runtimeParams(ii-1).file)
                    app.dualprint(fid,'%s new file started (%s)\n',datestr(runtimeParams(ii).time,'dd-mmm-yyyy HH:MM:SS.FFF'),runtimeParams(ii).file);
                end
                
                % check parameters compared to previous datagram
                fields = fieldnames(runtimeParams(ii).params);
                for kk = 1:numel(fields)
                    flag = 0;
                    doesFieldPreviouslyExist = isfield(runtimeParams(ii-1).params,fields{kk});
                    if doesFieldPreviouslyExist
                        isCurValIdentical = strcmp(runtimeParams(ii-1).params.(fields{kk}),runtimeParams(ii).params.(fields{kk}));
                        if isCurValIdentical
                            % field value identical to previous datagram.
                            % Do nothing
                        else
                            % field value different. Display
                            app.dualprint(fid,'   %s PARAMETER CHANGED. %s was ''%s'', and now ''%s''\n',datestr(runtimeParams(ii).time,'dd-mmm-yyyy HH:MM:SS.FFF'),fields{kk},runtimeParams(ii-1).params.(fields{kk}),runtimeParams(ii).params.(fields{kk}));
                        end
                    else
                        % brand new parameter
                        app.dualprint(fid,'   %s NEW PARAMETER. %s: %s\n',datestr(runtimeParams(ii).time,'dd-mmm-yyyy HH:MM:SS.FFF'),fields{kk},runtimeParams(ii).params.(fields{kk}));
                    end
                    
                end

            end
            
            % at the end, produce summary results:
            app.dualprint(fid,'\nSUMMARY RESULTS:\n');
            allFields = arrayfun(@(s) fieldnames(s.params),runtimeParams','UniformOutput',false);
            allFields = unique(vertcat(allFields{:}));
            for kk = 1:numel(allFields)
                thisField = allFields{kk};
                dtgsThatHaveThisField = arrayfun(@(s) isfield(s.params,thisField),runtimeParams);
                % find unique values for this field across all runtime
                % parameters datagram that have it
                allValuesForThisField = arrayfun(@(s) s.params.(thisField),runtimeParams(dtgsThatHaveThisField),'UniformOutput',false);
                uniqueValuesForThisField = unique(allValuesForThisField); 
                if all(dtgsThatHaveThisField)
                    % all datagrams have this field
                    if numel(uniqueValuesForThisField) == 1
                        app.dualprint(fid,' * constant %s: %s\n',thisField, uniqueValuesForThisField{1});
                    else
                        listOfValues = strjoin(uniqueValuesForThisField,', ');
                        app.dualprint(fid,' * VARIABLE %s: %s\n',thisField, listOfValues);
                    end
                else
                    % not all datagrams have this field
                    if numel(uniqueValuesForThisField) == 1
                        app.dualprint(fid,' * OCCASIONAL but constant %s: %s\n',thisField, uniqueValuesForThisField{1});
                    else
                        listOfValues = strjoin(uniqueValuesForThisField,', ');
                        app.dualprint(fid,' * OCCASIONAL AND VARIABLE %s: %s\n',thisField, listOfValues);
                    end
                end
            end
            
            % close file
            fclose(fid);
            
            % close progress dialog and add a final dialog
            if progDlg.CancelRequested
                msg = sprintf('%s cancelled. Find partial results at %s',titleBatchProc,exportFile);
                icon = 'warning';
            else
                msg = sprintf('%s complete. Find results at %s',titleBatchProc,exportFile);
                icon = 'success';
            end
            close(progDlg);
            uialert(app.MainFigure,msg,titleBatchProc,'Icon',icon);
            
        end

        % Menu selected function: LineanalysisMenu
        function Menu_Tools_BatchProc_LineAna_Selected(app, event)
            % Run the line analysis on a bunch of files without saving the
            % converted files on the hard-drive and without loading them in
            % Iskaffe. This way, no limitation on number of files to run
            % the analysis on. 
            %
            % Give in input a folder of raw files, go through all files one
            % at a time, and for each, extract the datagrams, run the algo
            % to detect the number of bad beams, bad pings, etc.,
            % export/save the results... and when it's all done, summarize
            % and export the results.
            %
            % When you have something that works, modify it by using a
            % config/project file to allow resuming a job that was
            % interrupted. This log file would list the folder and all the
            % files with checks that the folder contents have not changed.
            
            % prompt for input folder of raw data files to process
            titleBatchProc = 'Batch Line Analysis';
            filesList = app.promptForInputFiles(titleBatchProc,{'.all','.kmall'});
            
            % check number of files, and prompt for confirmation
            nFiles = numel(filesList);
            if nFiles == 0
                msg = 'No raw data files found. Processing cancelled';
                uialert(app.MainFigure,msg,titleBatchProc);
                fprintf('%s cancelled.', titleBatchProc);
                return
            else
                msg = sprintf('%i files will be analysed. This could take a while. Confirm?',nFiles);
                selection = uiconfirm(app.MainFigure,msg,titleBatchProc);
                switch selection
                    case 'Cancel'
                        fprintf('%s cancelled.', titleBatchProc);
                        return
                end
            end
            
            % prompt for output file of results to export
            exportFile = promptForExportFile(app, titleBatchProc, 'batch_line_analysis', 'csv');
          
            % if cancelling the dialog box, exit here
            if isempty(exportFile)
                fprintf('%s cancelled.', titleBatchProc);
                return
            end

            % initialise a table to log the results for each file
            results.File = filesList;
            results.nPings = nan(nFiles,1);
            results.nBadPings = nan(nFiles,1);
            results.percBadPings = nan(nFiles,1);
            results.nSoundings = nan(nFiles,1);
            results.nBadSoundings = nan(nFiles,1);
            results.percBadSoundings = nan(nFiles,1);
            
            % start progress dialog
            progDlg = uiprogressdlg(app.MainFigure,...
                'Title',titleBatchProc,...
                'Cancelable','on');
            
            % start a loop for each file
            for ii = 1:nFiles
                
                % Check for Cancel button press
                if progDlg.CancelRequested
                    break
                end
                
                file = results.File{ii};
                
                % comms
                progDlg.Value = ii./nFiles;
                progDlg.Message = sprintf('Analyzing file %i/%i',ii,nFiles);
                
                fprintf('%i/%i: line %s\n',ii,nFiles,file);
                fprintf('  Reading file...\n')
                switch CFF_file_extension(file)
                    case '.all'
                        ALLdata = CFF_read_all(file,88);
                        fData = CFF_convert_ALLdata_to_fData(ALLdata);
                    case '.kmall'
                        KMALLdata = CFF_read_kmall(file,'#MRZ');
                        fData = CFF_convert_KMALLdata_to_fData(KMALLdata);
                end
                
                fprintf('  Analyzing file...\n')
                fData = CFF_analyze_backscatter({fData});
                fData = fData{1};
                
                % log the results in the table
                results.nPings(ii,1) = numel(fData.X8_1P_PingCounter);
                results.nBadPings(ii,1) = sum(fData.X_1P_badPing);
                results.percBadPings(ii,1) = round(results.nBadPings(ii).*100./results.nPings(ii),2);
                results.nSoundings(ii,1) = numel(fData.X8_BP_ReflectivityBS);
                results.nBadSoundings(ii,1) = sum(~fData.X_BP_goodData(:));
                results.percBadSoundings(ii,1) = round(results.nBadSoundings(ii).*100./results.nSoundings(ii),2);
                
                % more dispplay
                fprintf('  Done. Bad pings: %i/%i (%.2f%%). Bad soundings: %i/%i (%.2f%%)\n',...
                    results.nBadPings(ii,1), results.nPings(ii,1),results.percBadPings(ii,1),...
                    results.nBadSoundings(ii,1), results.nSoundings(ii,1), results.percBadSoundings(ii,1));

            end
            
            % summary per file
            resultsTable = struct2table(results);
            
            % compiled results
            resultsTable(end+1,:) = {'total',sum(results.nPings),sum(results.nBadPings),...
                round(sum(results.nBadPings).*100./sum(results.nPings),2),...
                sum(results.nSoundings),sum(results.nBadSoundings),...
                round(sum(results.nBadSoundings).*100./sum(results.nSoundings),2)};
            
            % display results
            disp(resultsTable);
            
            % export result
            writetable(resultsTable,exportFile);
            
            % close progress dialog and add a final dialog
            if progDlg.CancelRequested
                msg = sprintf('Batch line analysis cancelled. Find partial results at %s',exportFile);
                icon = 'warning';
            else
                msg = sprintf('Batch line analysis complete. Find results at %s',exportFile);
                icon = 'success';
            end
            close(progDlg);
            uialert(app.MainFigure,msg,titleBatchProc,'Icon',icon);
            
        end

        % Button pushed function: FilesTab_Folder_Button
        function FilesTab_Folder_Button_Pushed(app, event)
            % Open folder selection dialog box
            currentFolder = app.FilesTab_Folder_EditField.Value;
            newFolder = uigetdir(currentFolder,'Select folder of raw data files');
            figure(app.MainFigure); % uigetdir sometimes puts app in the background. Bring it back on top
            if newFolder ~= 0
                % Update value of the FolderEditField
                app.FilesTab_Folder_EditField.Value = newFolder;
                % Update Raw Files Table contents
                app.updateRawFilesTable();
                % Add as last data folder in config file
                app.set_config_field('lastDataPath',newFolder);
            end
        end

        % Value changed function: FilesTab_Folder_EditField
        function FilesTab_Folder_EditField_ValueChanged(app, event)
            % Check if value is an existing folder
            requestedFolder = app.FilesTab_Folder_EditField.Value;
            if ~isfolder(requestedFolder)
                % Error dialog box
                uialert(app.MainFigure,'Folder not found','Invalid Folder','CloseFcn','uiresume(gcbf)');
                uiwait(app.MainFigure);
                % Reset previous field value
                app.FilesTab_Folder_EditField.Value = event.PreviousValue;
            else
                % Update Raw Files Table contents
                app.updateRawFilesTable();
                % Add as last data folder in config file
                app.set_config_field('lastDataPath',requestedFolder);
            end
        end

        % Menu selected function: FilesTab_SelectAll_Menu
        function FilesTab_SelectAll_Menu_Selected(app, event)
            app.FilesTab_Table.Data(:,1) = cellfun(@(x) {true}, app.FilesTab_Table.Data(:,1));
        end

        % Menu selected function: FilesTab_SelectConverted_Menu
        function FilesTab_SelectConverted_Menu_Selected(app, event)
            app.FilesTab_Table.Data(:,1) = arrayfun(@(x) {x}, app.idxConverted);
        end

        % Menu selected function: FilesTab_DeselectAll_Menu
        function FilesTab_DeselectAll_Menu_Selected(app, event)
            app.FilesTab_Table.Data(:,1) = cellfun(@(x) {false}, app.FilesTab_Table.Data(:,1));
        end

        % Menu selected function: FilesTab_InverseSelection_Menu
        function FilesTab_InverseSelection_Menu_Selected(app, event)
            app.FilesTab_Table.Data(:,1) = cellfun(@(x) {~x}, app.FilesTab_Table.Data(:,1));
        end

        % Button pushed function: FilesTab_Convert_Button
        function FilesTab_Convert_Button_Pushed(app, event)
            % Get idx of selected files
            idxFilesToConvert = cell2mat(app.FilesTab_Table.Data(:,1));
            % Default value to be overwritten if relevant
            forceReconvert = 0;
            % Prompt if some files already converted
            if any(idxFilesToConvert.*app.idxConverted)
                N = sum(idxFilesToConvert.*app.idxConverted);
                if N == 1
                    questDlgTxt = sprintf('1 file for which conversion was requested is already converted.\n\nDo you want to re-convert it?');
                else
                    questDlgTxt = sprintf('%i files for which conversion was requested are already converted.\n\nDo you want to re-convert them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Raw Files Conversion', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        forceReconvert = 1;
                    case 'No'
                        forceReconvert = 0;
                    case 'Cancel'
                        % abort converting
                        return
                end
            end
            % Get filenames
            rawFilesList = app.rawDataFilesList(idxFilesToConvert);
            % Convert files
            CFF_convert_raw_files(rawFilesList,...
                'conversionType','seafloor',...
                'saveFDataToDrive',1,...
                'forceReconvert',forceReconvert,...
                'outputFData',0,...
                'abortOnError',0,...
                'convertEvenIfDtgrmsMissing',0,...
                'dr_sub',1,...
                'db_sub',1,...
                'comms','multilines');
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
        end

        % Button pushed function: FilesTab_Load_Button
        function FilesTab_Load_Button_Pushed(app, event)
            % Get idx of selected files
            idxFilesSelected = cell2mat(app.FilesTab_Table.Data(:,1));
            idxFilesToLoad = idxFilesSelected & ~app.idxLoaded;
            % Idx of files requested but not converted yet
            idxNeedConversionFirst = idxFilesToLoad & ~app.idxConverted;
            % Prompt if some files not already converted
            if any(idxNeedConversionFirst)
                N = sum(idxNeedConversionFirst);
                if N == 1
                    questDlgTxt = sprintf('1 file for which loading was requested has not been converted yet.\n\nDo you want to convert it and load it?');
                else
                    questDlgTxt = sprintf('%i files for which loading was requested have not been converted yet.\n\nDo you want to convert them and load them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Converted Files Loading', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        % Convert those files
                        CFF_convert_raw_files(app.rawDataFilesList(idxNeedConversionFirst),...
                            'conversionType','seafloor',...
                            'saveFDataToDrive',1,...
                            'forceReconvert',0,...
                            'outputFData',0,...
                            'abortOnError',0,...
                            'convertEvenIfDtgrmsMissing',0,...
                            'dr_sub',1,...
                            'db_sub',1,...
                            'comms','multilines');
                        % Update Raw Files Table contents
                        app.updateRawFilesTable(1);
                    case 'No'
                        % Remove non-converted files from loading list
                        idxFilesToLoad = logical(idxFilesToLoad.*app.idxConverted);
                    case 'Cancel'
                        % abort loading
                        return
                end
            end
            % list of files to load
            toLoadFilesList = app.rawDataFilesList(idxFilesToLoad);
            nFiles = numel(toLoadFilesList);
            if isempty(toLoadFilesList)
                return
            end
            % load files
            fData_new = CFF_load_converted_files(toLoadFilesList,...
                'fixPaths',1,...
                'abortOnError',0,...
                'comms','multilines');
            % initialize navigation processing parameters
            navProcParams = struct();
            if ~isempty(app.datagramSource)
                navProcParams.datagramSource = app.datagramSource;
            end
            if ~isempty(app.ellips)
                navProcParams.ellips = app.ellips;
            end
            if ~isempty(app.tmproj)
                navProcParams.tmproj = app.tmproj;
            end
            % chain process:
            % 1) navigation processing
            % 2) georeference WC bottom detect
            procFun{1} = @CFF_compute_ping_navigation_v2;
            procMsg{1} = 'Processing navigation and heading';
            procParams{1} = navProcParams;
            procFun{2} = @CFF_georeference_bottom_detect;
            procMsg{2} = 'Georeferencing the bottom detections';
            procParams{2} = struct(); % no parameters needed for that function
            [fData_new,outParams] = CFF_group_processing(procFun,fData_new,procParams,...
                'procMsg',procMsg,...
                'abortOnError',0,...
                'saveFDataToDrive',0,...
                'comms','multilines');
            navProcParams = outParams{1};
            % update app's geoprocessing parameters
            app.datagramSource = navProcParams.datagramSource;
            app.ellips = navProcParams.ellips;
            app.tmproj = navProcParams.tmproj;
            % add new fData to old one
            app.FData = [app.FData,fData_new];
            % Update loaded lines tab contents
            app.updateLoadedLines();
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
            % Update Map and zoom out
            app.updateMap();
            app.zoomAllTheWayOut();
            % Update buttons
            app.updateButtons();
            % Update list of data modes
            app.updateListOfModes();
            % Update analysis results label
            updateAnalysisResultsLabel(app);
        end

        % Menu selected function: LinesTab_UnloadSelected_Menu
        function LinesTab_UnloadSelected_Menu_Selected(app, event)
            % get names of lines to unload
            linesToUnload = app.LinesTab_ListBox.Value;
            % corresponding indices in fData
            nLines = numel(app.FData);
            idxUnload = false(nLines,1);
            for ii = 1:nLines
                thisLineName = CFF_file_name(CFF_onerawfileonly(app.FData{ii}.ALLfilename));
                if ismember(thisLineName, linesToUnload)
                    idxUnload(ii) = true;
                end
            end
            % remove lines from fData
            app.FData(idxUnload) = [];
            % Update loaded lines Tab
            updateLoadedLines(app);
            % Update Raw Files Tab (keep selection)
            app.updateRawFilesTable(1);
            % Cleanup Map
            cleanupMap(app);
            % Update buttons
            app.updateButtons();
            % Update list of data modes
            app.updateListOfModes();
            % clear up info if no more lines loaded
            if isempty(app.FData)
                app.ellips = '';
                app.tmproj = '';
            end
        end

        % Menu selected function: LinesTab_MostRecentLinesOnTop_Menu
        function LinesTab_MostRecentLinesOnTop_Menu_Selected(app, event)
            % to check
            
            % sort by time of first ping
            loadedLinesFirstPingSDN = cellfun(@(x) x.X_1P_pingSDN(1), app.FData,'UniformOutput',true);
            [~,I] = sort(loadedLinesFirstPingSDN,'descend');
            app.FData = app.FData(I);
            app.FDataModeInd1P = app.FDataModeInd1P(I);
            
            % Update loaded lines Tab
            updateLoadedLines(app);
            
            % Update order of elements on map
            app.reorderLinesGraphObjects();
            
        end

        % Menu selected function: LinesTab_OldestLinesOnTop_Menu
        function LinesTab_OldestLinesOnTop_Menu_Selected(app, event)
            % to check
            
            % sort by time of first ping
            loadedLinesFirstPingSDN = cellfun(@(x) x.X_1P_pingSDN(1), app.FData,'UniformOutput',true);
            [~,I] = sort(loadedLinesFirstPingSDN,'ascend');
            app.FData = app.FData(I);
            app.FDataModeInd1P = app.FDataModeInd1P(I);
            
            % Update loaded lines Tab
            updateLoadedLines(app);
            
            % Update order of elements on map
            app.reorderLinesGraphObjects();
            
        end

        % Button pushed function: ProcessTab_Grid_Button
        function ProcessTab_Grid_Button_Pushed(app, event)
            if isempty(app.FData)
                return
            end
            % grid requested lines
            app.FData = CFF_grid_lines(app.FData,...
                'res',app.ProcessTab_GridSize_EditField.Value, ... % grid resolution
                'saveFDataToDrive', 0, ... % don't save (gridded) fData to drive
                'abortOnError', 0, ... % move on to next file
                'comms','multilines');
            % Set gridded data displayed to backscatter
            app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Value = 'Backscatter';
            % Update Map and zoom out
            app.updateMap();
            app.zoomAllTheWayOut();
            % Update buttons enable state
            app.updateButtons();
            % Update analysis results label
            app.updateAnalysisResultsLabel();
        end

        % Button pushed function: ProcessTab_Analyze_Button
        function ProcessTab_Analyze_Button_Pushed(app, event)
            
            if isempty(app.FData)
                return
            end
            
            app.FData = CFF_analyze_backscatter(app.FData,...
                'comms','multilines');
            
            % Check the "Show line analysis results" box
            app.ProcessTab_ShowLineAnalysis_CheckBox.Value = 1;
            % Update Map and zoom out
            app.updateMap();
            app.zoomAllTheWayOut();
            % Update buttons enable state
            app.updateButtons();
            % Update analysis results label
            app.updateAnalysisResultsLabel();
            
        end

        % Value changed function: 
        % ProcessTab_GriddedDataDisplayed_DropDownMenu
        function ProcessTab_GriddedDataDisplayed_DropDownMenu_ValueChanged(app, event)
            if isempty(app.FData)
                return
            end
            % Update Map but keep the same view
            app.updateMap();
        end

        % Value changed function: 
        % ProcessTab_ShowLineAnalysis_CheckBox
        function ProcessTab_ShowLineAnalysis_CheckBox_ValueChanged(app, event)
            value = app.ProcessTab_ShowLineAnalysis_CheckBox.Value;
            nLines = numel(app.FData);
            for ii = 1:nLines
                lineID = num2str(app.FData{ii}.ID,'%.0f');
                lineBadDataID = [lineID '_bad'];
                lineBadDataObj = findall(app.Main_Axes,'Tag',lineBadDataID);
                set(lineBadDataObj,'Visible',value);
            end
        end

        % Button pushed function: ProcessTab_File_Button
        function ProcessTab_File_Button_Pushed(app, event)
            % Import a boundary file for later msurvey analysis
            
            % get user input
            filter = {...
                '*.csv;*.txt', 'Text files'; ...
                '*.shp',       'Shapefiles'};
            title = 'Select survey limits file';
            defname = app.UserFolder;
            [filename, pathname, filterindex] = uigetfile(filter,title,defname);
            figure(app.MainFigure); % uigetfile sometimes puts app in the background. Bring it back on top
            
            % if cancelling the dialog box, exit here
            if filename == 0
                return
            end
            
            % get filename
            file = fullfile(pathname, filename);
            
            % read file, test suitability, and process
            switch filterindex
                case 1
                    % text file
                    pos = readmatrix(file);
                    
                    % for text file we simply assume that the coordsinates
                    % are in the right projection. So we only test for
                    % suitability as polygon vertices
                    
                    % test for suitability as vertices coordinates
                    % two columns only, X & Y
                    % 3 points minimum
                    % actual numbers, no nans
                    if size(pos,2)==2 &&  size(pos,1)>=3 &&  all(~isnan(pos(:)))
                        % test if lat/long
                        if all(abs(pos(:,1))<=180) && all(abs(pos(:,2))<=90)
                            % assuming input is lat/long in decimal degrees
                            if isempty(app.ellips) && isempty(app.tmproj)
                                % project does not have a CRS yet.
                                % get UTM zone from first vertex and set
                                % app CRS from this
                                [~,~,~,~,utmzone] = CFF_ll2tm(pos(1,1), pos(1,2),'wgs84','utm');
                                app.tmproj = ['utm' utmzone];
                                app.ellips = 'wgs84';
                            end
                            % project latlong using app CRS
                            [pos(:,1), pos(:,2)] = CFF_ll2tm(pos(:,1), pos(:,2), app.ellips, app.tmproj);
                        else
                            % assuming input is the correct UTM projection
                        end
                        isSuitable = 1;
                    else
                        isSuitable = 0;
                    end
                    
                case 2
                    % shapefile
                    S = shaperead(file);
                    
                    % default as unsuitable
                    isSuitable = 0;
                    
                    % keep only polygons in shapefile
                    iPolyg = arrayfun(@(elm) strcmp(elm.Geometry,'Polygon'),S) == 1;
                    S = S(iPolyg);
                    
                    N = numel(S);
                    if N>1
                        % more than one polygon in shapefile, need to
                        % choose one
                        
                        % get attributes
                        Sfields = fields(S);
                        indf = ismember(Sfields,{'Geometry','BoundingBox','X','Y'});
                        Sfields = Sfields(~indf);
                        
                        % create string from attributes
                        clear list
                        for jj = 1:N
                            clear list0
                            for kk = 1:numel(Sfields)
                                f = Sfields{kk};
                                v = getfield(S(jj),f);
                                if isnumeric(v)
                                    v = num2str(v);
                                end
                                list0{kk} = sprintf('%s: %s',f,v);
                            end
                            list(jj,1) = join(list0,', ');
                        end
                        
                        % prompt for choice of polygon
                        [indx,tf] = listdlg('PromptString','Select a polygon', ...
                            'ListSize',[300 300], ...
                            'SelectionMode','single', ...
                            'ListString',list);
                        if tf==1
                            S = S(indx);
                        else
                            % cancel
                            S = [];
                        end
                    end
                    
                    % if we have a polygon
                    if ~isempty(S)
                        % get coordinates
                        pos = [S.X' S.Y'];
                        % remove nans
                        pos(isnan(pos(:,1))|isnan(pos(:,2)),:) = [];
                        % remove last vertex if same as first
                        if all(pos(end,:)==pos(1,:))
                            pos(end,:) = [];
                        end
                        % now we check and process for coordinate system
                        % get CRS from shapefile
                        info = shapeinfo(file);
                        if isa(info.CoordinateReferenceSystem,'geocrs')
                            % lat/long
                            if strcmp(info.CoordinateReferenceSystem.Name,'WGS 84')
                                if isempty(app.ellips) && isempty(app.tmproj)
                                    % project does not have a CRS yet.
                                    % get UTM zone from first vertex and set
                                    % app CRS from this
                                    [~,~,~,~,utmzone] = CFF_ll2tm(pos(1,1), pos(1,2),'wgs84','utm');
                                    app.tmproj = ['utm' utmzone];
                                    app.ellips = 'wgs84';
                                end
                                % project latlong using app CRS
                                [pos(:,1), pos(:,2)] = CFF_ll2tm(pos(:,1), pos(:,2), app.ellips, app.tmproj);
                                isSuitable = 1;
                            end
                        elseif isa(info.CoordinateReferenceSystem,'projcrs')
                            % projected coordinates
                            if strcmp(info.CoordinateReferenceSystem.ProjectionMethod,'Transverse Mercator')
                                % get zone
                                pat = "UTM zone " + digitsPattern(2) + ("N"|"S");
                                newStr = char(extract(info.CoordinateReferenceSystem.Name,pat));
                                filetmproj = ['utm' newStr(end-2:end)];
                                if isempty(app.ellips) && isempty(app.tmproj)
                                    % not loaded any data yet, so use that CRS
                                    % for the app
                                    app.tmproj = filetmproj;
                                    app.ellips = 'wgs84';
                                    isSuitable = 1;
                                else
                                    % check that it matches the app CRS
                                    if strcmp(filetmproj, app.tmproj)
                                        isSuitable = 1;
                                    end
                                end
                            end
                        end
                    end
            end
            
            % warning and exit if not suitable
            if ~isSuitable
                % Error dialog box
                uialert(app.MainFigure,'Boundary file not suitable','Boundary file not suitable','CloseFcn','uiresume(gcbf)');
                uiwait(app.MainFigure);
                return
            end
            
            comms = CFF_Comms('multilines');
            comms.start('Importing survey limits');
            
            % create polygon
            app.createROIpolygon(pos);
            
            comms.finish('Done');
            
            % zoom out to all graphical objects
            app.zoomAllTheWayOut();
            
            % update buttons
            app.updateButtons();
            
        end

        % Button pushed function: ProcessTab_Draw_Button
        function ProcessTab_Draw_Button_Pushed(app, event)
            
            % set warning
            selection = uiconfirm(app.MainFigure, ...
                {'About to start drawing the survey limits ROI.','This will clear any currently existing ROI.'},...
                'ROI drawing mode');
            switch selection
                case 'OK'
                    app.createROIpolygon();
                    
                    % update buttons
                    app.updateButtons();
            end
        end

        % Button pushed function: ProcessTab_Edit_Button
        function ProcessTab_Edit_Button_Pushed(app, event)
            
            % get ROI
            h = findobj('Tag','ROI');
            
            switch h.InteractionsAllowed
                case 'none'
                    % currently in normal mode. Pass in edit mode
                    
                    % set warning
                    selection = uiconfirm(app.MainFigure, ...
                        {'About to start editing the survey limits ROI.','When finished, press the "Edit" button again to confirm.'},...
                        'ROI editing mode');
                    
                    switch selection
                        case 'OK'
                            h.InteractionsAllowed = 'reshape';
                    end
                case 'reshape'
                    % currently in edit mode. Pass in normal mode
                    h.InteractionsAllowed = 'none';
            end
            
            
        end

        % Button pushed function: ProcessTab_Clear_Button
        function ProcessTab_Clear_Button_Pushed(app, event)
            % delete ROI polygon
            app.deleteROIpolygon();
            % update buttons
            app.updateButtons();
            % DO NOT zoom out. Keep same map view
        end

        % Button pushed function: ProcessTab_AnalyzeSurvey_Button
        function ProcessTab_AnalyzeSurvey_Button_Pushed(app, event)
            if isempty(app.FData)
                return
            end
            % area to mosaic
            roi = findobj('Tag','ROI');
            if ~isempty(roi)
                xy_roi = roi.Position;
            end
            % mosaic requested lines
            %             mosaicBathy = CFF_mosaic_lines(app.FData,...
            %                 'X_NE_bathy', ...
            %                 'xy_roi', xy_roi, ... % mosaic limits polygon
            %                 'res',[], ... % get grid size directly from fData
            %                 'mode','blend', ... % mosaicking mode
            %                 'comms','multilines');
            %             mosaicBS = CFF_mosaic_lines(app.FData,...
            %                 'X_NE_bs', ...
            %                 'xy_roi', xy_roi, ... % mosaic limits polygon
            %                 'res',[], ... % get grid size directly from fData
            %                 'mode','stitch', ... % mosaicking mode
            %                 'comms','multilines');
            app.mosaicMinBeamAngle = CFF_mosaic_lines(app.FData,...
                'X_NE_minBeamAngle', ...
                'xy_roi', xy_roi, ... % mosaic limits polygon
                'res',[], ... % get grid size directly from fData
                'mode','min', ... % mosaicking mode
                'comms','multilines');
            
            comms = CFF_Comms('multilines');
            comms.start('Finding missing data in mosaic');
            
            % find where data is missing in mosaic
            [xg,yg] = meshgrid(app.mosaicMinBeamAngle.xg,app.mosaicMinBeamAngle.yg);
            app.mosaicInPolygon = inpolygon(xg,yg,app.mosaicMinBeamAngle.x_pol,app.mosaicMinBeamAngle.y_pol);
            app.mosaicMissing = app.mosaicInPolygon & app.mosaicMinBeamAngle.weight==0;
            
            comms.finish('Done');
            
            % Delete any already existing results
            delete(findobj('Tag','surveyAnalysisResults'));
            
            % and display the new one
            imagesc(app.Survey_Axes,...
                app.mosaicMinBeamAngle.xg,...
                app.mosaicMinBeamAngle.yg,...
                app.mosaicMissing,...
                'AlphaData',app.mosaicMissing==1,...
                'Tag','surveyAnalysisResults');
            colormap(app.Survey_Axes,'cool')
            
            % for some reason, the YDir prop keeps being wrong
            set(app.Survey_Axes,'YDir','normal');
            
            % check box
            app.ProcessTab_ShowResults_CheckBox.Value = 1;
            
            % Update Map and zoom out
            app.updateMap();
            app.zoomAllTheWayOut();
            
            % Update buttons
            app.updateButtons();
            
            % Update analysis results label
            app.updateAnalysisResultsLabel();
        end

        % Value changed function: ProcessTab_ShowResults_CheckBox
        function ProcessTab_ShowResults_CheckBox_ValueChanged(app, event)
            value = app.ProcessTab_ShowResults_CheckBox.Value;
            set(findobj('Tag','surveyAnalysisResults'),'Visible',value);
        end

        % Button pushed function: 
        % ProcessTab_ExportSurveyAnalysis_Button
        function ProcessTab_ExportSurveyAnalysis_Button_Pushed(app, event)
            
            % current date and time
            datetimenow = now;
            export_time_txt = datestr(datetimenow,'yyyy-mm-dd_HHMMSS');
            
            % default export Folder
            defaultExportFolder = fullfile(app.UserFolder,'exports');
            if ~isfolder(defaultExportFolder)
                mkdir(defaultExportFolder);
            end
            
            % default export file name
            defaultfilename = fullfile(defaultExportFolder,sprintf('%s_results_%s.tif',app.Name,export_time_txt));
            
            % user prompt
            [file,path] =  uiputfile('*.tif','Export survey analysis results',defaultfilename);
            figure(app.MainFigure); % uiputfile sometimes puts app in the background. Bring it back on top
            % if cancelling the dialog box, exit here
            if file == 0
                return
            end
            
            % get filename
            filename = fullfile(path, file);
            
            comms = CFF_Comms('multilines');
            comms.start('Exporting analysis results');
            
            % map cells reference object
            xlimits = [app.mosaicMinBeamAngle.xg(1)-app.mosaicMinBeamAngle.res./2, app.mosaicMinBeamAngle.xg(end)+app.mosaicMinBeamAngle.res./2];
            ylimits = [app.mosaicMinBeamAngle.yg(1)-app.mosaicMinBeamAngle.res./2, app.mosaicMinBeamAngle.yg(end)+app.mosaicMinBeamAngle.res./2];
            rasterSize = size(app.mosaicMissing);
            R = maprefcells(xlimits,ylimits,rasterSize);
            
            % coordinate reference
            switch app.ellips
                case 'wgs84'
                    switch app.tmproj(1:3)
                        case 'utm'
                            switch app.tmproj(end)
                                case 'N'
                                    coordRefSysCode = str2num(['326' app.tmproj(4:5)]);
                                case 'S'
                                    coordRefSysCode = str2num(['327' app.tmproj(4:5)]);
                            end
                        otherwise
                            % not coded
                    end
                otherwise
                    % not coded
            end
            
            % write geotif file
            geotiffwrite(filename,app.mosaicMissing,R,'CoordRefSysCode',coordRefSysCode);
            
            % text file
            filename_txt = [filename(1:end-4), '.txt'];
            
            % write
            writecell(app.ProcessTab_AnalysisResults_TextArea.Value,filename_txt, 'QuoteStrings', false);
            
            comms.finish('Done');
            
        end

        % Callback function
        function MainFigure_WindowButtonMotion(app, event)
            % Callback when pointer is moving over the figure
            
            % Determine if mouse is within map
            cp = app.MainFigure.CurrentPoint;
            isInAxes = cp(1) >= app.Main_Axes.Position(1) && ...
                cp(1) <= sum(app.Main_Axes.Position([1,3])) && ...
                cp(2) >= app.Main_Axes.Position(2) && ...
                cp(2) <= sum(app.Main_Axes.Position([2,4]));
            if isInAxes
                % Update displayed coordinates
                app.ProcessTab_ButtonMotionTextArea.Value{1} = sprintf('E: %.2f', app.Main_Axes.CurrentPoint(1,1));
                app.ProcessTab_ButtonMotionTextArea.Value{2} = sprintf('N: %.2f', app.Main_Axes.CurrentPoint(1,2));
            else
                % Clear displayed coordinates
                app.ProcessTab_ButtonMotionTextArea.Value{1} = '';
                app.ProcessTab_ButtonMotionTextArea.Value{2} = '';
            end
            
        end

        % Callback function
        function MapObject_ButtonDown(app, event)
            % Callback when clicking on a graphical object in the map
            
            % click location info
            app.ProcessTab_ButtonDownTextArea.Value{1} = sprintf('E: %.2f', event.IntersectionPoint(1));
            app.ProcessTab_ButtonDownTextArea.Value{2} = sprintf('N: %.2f', event.IntersectionPoint(2));
            
            % event holds the object where it was clicked
            if strcmp(event.Source.Tag,'MainAxes')
                % click on empty space in the map
                app.ProcessTab_ButtonDownTextArea.Value{3} = '';
                app.ProcessTab_ButtonDownTextArea.Value{4} = '';
                
            elseif ~isempty(event.Source.Tag)
                % anything else should be a graphic object with ID
                
                % index in fData
                ID = str2double(cell2mat(extract(event.Source.Tag, digitsPattern)));
                ind = cellfun(@(x) x.ID==ID, app.FData);
                
                % get the filename
                lineName = CFF_file_name(app.FData{ind}.ALLfilename{1},1);
                
                % get closest ping
                dE = app.FData{ind}.X_1P_pingE - event.IntersectionPoint(1);
                dN = app.FData{ind}.X_1P_pingN - event.IntersectionPoint(2);
                [~,iPing] = min(sqrt(dE.^2+dN.^2));
                
                % get nadir beam
                [~,iBeam] = min(abs(app.FData{ind}.X8_BP_AcrosstrackDistanceY(:,iPing)));
                
                % get depth
                nadirDepth = app.FData{ind}.X8_BP_DepthZ(iBeam,iPing);
                
                % update text area
                app.ProcessTab_ButtonDownTextArea.Value{3} = lineName;
                app.ProcessTab_ButtonDownTextArea.Value{4} = sprintf('Z: %.2f',nadirDepth);
                
            end
            
            
        end

        % Close request function: MainFigure
        function MainFigure_CloseRequest(app, event)
                        
            % last messages and diary off
            fprintf('Closing %s...\n',app.Name);
            fprintf('Find a log of this output at %s.\n',app.DiaryFile)
            fprintf('Bye.\n');
            diary('off');
            
            % delete the app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Iskaffe';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigure_CloseRequest, true);
            app.ToolsMenu = uimenu(app.MainFigure);
            app.ToolsMenu.Text = 'Tools';
            app.BatchprocessingMenu = uimenu(app.ToolsMenu);
            app.BatchprocessingMenu.Text = 'Batch processing';
            app.ParametersanalysisKoppMenu = uimenu(app.BatchprocessingMenu);
            app.ParametersanalysisKoppMenu.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_BatchProc_ParamAna_Selected, true);
            app.ParametersanalysisKoppMenu.Text = 'Parameters analysis (Kopp)';
            app.LineanalysisMenu = uimenu(app.BatchprocessingMenu);
            app.LineanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_BatchProc_LineAna_Selected, true);
            app.LineanalysisMenu.Text = 'Line analysis';
            app.MainFigure_GridLayout = uigridlayout(app.MainFigure);
            app.TabGroup = uitabgroup(app.MainFigure_GridLayout);
            app.Files_Tab = uitab(app.TabGroup);
            app.Files_Tab.Title = 'Raw files';
            app.FilesTab_GridLayout = uigridlayout(app.Files_Tab);
            app.FilesTab_Buttons_GridLayout = uigridlayout(app.FilesTab_GridLayout);
            app.FilesTab_Load_Button = uibutton(app.FilesTab_Buttons_GridLayout, 'push');
            app.FilesTab_Load_Button.ButtonPushedFcn = createCallbackFcn(app, @FilesTab_Load_Button_Pushed, true);
            app.FilesTab_Load_Button.Text = 'Load';
            app.FilesTab_Convert_Button = uibutton(app.FilesTab_Buttons_GridLayout, 'push');
            app.FilesTab_Convert_Button.ButtonPushedFcn = createCallbackFcn(app, @FilesTab_Convert_Button_Pushed, true);
            app.FilesTab_Convert_Button.Text = 'Convert';
            app.FilesTab_Table = uitable(app.FilesTab_GridLayout);
            app.FilesTab_Table.ColumnName = {''; 'File'; 'Folder'};
            app.FilesTab_Table.RowName = {};
            app.FilesTab_Table.ColumnSortable = [false true false];
            app.FilesTab_Table.ColumnEditable = [true false false];
            app.FilesTab_Folder_GridLayout = uigridlayout(app.FilesTab_GridLayout);
            app.EditFieldLabel = uilabel(app.FilesTab_Folder_GridLayout);
            app.EditFieldLabel.Text = 'Edit Field';
            app.FilesTab_Folder_EditField = uieditfield(app.FilesTab_Folder_GridLayout, 'text');
            app.FilesTab_Folder_EditField.ValueChangedFcn = createCallbackFcn(app, @FilesTab_Folder_EditField_ValueChanged, true);
            app.FilesTab_Folder_Button = uibutton(app.FilesTab_Folder_GridLayout, 'push');
            app.FilesTab_Folder_Button.ButtonPushedFcn = createCallbackFcn(app, @FilesTab_Folder_Button_Pushed, true);
            app.FilesTab_Folder_Button.Icon = 'foldericon.gif';
            app.FilesTab_Folder_Button.Text = '';
            app.Lines_Tab = uitab(app.TabGroup);
            app.Lines_Tab.Title = 'Loaded lines';
            app.LinesTab_GridLayout = uigridlayout(app.Lines_Tab);
            app.LinesTab_ListBox = uilistbox(app.LinesTab_GridLayout);
            app.LinesTab_ListBox.Items = {};
            app.LinesTab_ListBox.Multiselect = 'on';
            app.LinesTab_ListBox.Value = {};
            app.Label_2 = uilabel(app.LinesTab_GridLayout);
            app.Label_2.Text = '';
            app.Process_Tab = uitab(app.TabGroup);
            app.Process_Tab.Title = 'Process';
            app.ProcessTab_GridLayout = uigridlayout(app.Process_Tab);
            app.ProcessTab_AnalysisResults_Panel = uipanel(app.ProcessTab_GridLayout);
            app.ProcessTab_AnalysisResults_Panel.Title = 'Analysis Results';
            app.ProcessTab_AnalysisResults_GridLayout = uigridlayout(app.ProcessTab_AnalysisResults_Panel);
            app.Label_3 = uilabel(app.ProcessTab_AnalysisResults_GridLayout);
            app.Label_3.Text = '';
            app.ProcessTab_AnalysisResults_TextArea = uitextarea(app.ProcessTab_AnalysisResults_GridLayout);
            app.ProcessTab_Display_Panel = uipanel(app.ProcessTab_GridLayout);
            app.ProcessTab_Display_Panel.Title = 'Display';
            app.ProcessTab_Display_GridLayout = uigridlayout(app.ProcessTab_Display_Panel);
            app.ProcessTab_ButtonMotionTextArea = uitextarea(app.ProcessTab_Display_GridLayout);
            app.ProcessTab_ButtonDownTextArea = uitextarea(app.ProcessTab_Display_GridLayout);
            app.ProcessTab_SurveyProcessing_Panel = uipanel(app.ProcessTab_GridLayout);
            app.ProcessTab_SurveyProcessing_Panel.Title = 'Survey processing';
            app.ProcessTab_SurveyProcessing_GridLayout = uigridlayout(app.ProcessTab_SurveyProcessing_Panel);
            app.ProcessTab_ExportSurveyAnalysis_Button = uibutton(app.ProcessTab_SurveyProcessing_GridLayout, 'push');
            app.ProcessTab_ExportSurveyAnalysis_Button.ButtonPushedFcn = createCallbackFcn(app, @ProcessTab_ExportSurveyAnalysis_Button_Pushed, true);
            app.ProcessTab_ExportSurveyAnalysis_Button.Text = 'Export survey analysis';
            app.ProcessTab_ShowResults_CheckBox = uicheckbox(app.ProcessTab_SurveyProcessing_GridLayout);
            app.ProcessTab_ShowResults_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessTab_ShowResults_CheckBox_ValueChanged, true);
            app.ProcessTab_ShowResults_CheckBox.Text = 'Show results';
            app.ProcessTab_AnalyzeSurvey_Button = uibutton(app.ProcessTab_SurveyProcessing_GridLayout, 'push');
            app.ProcessTab_AnalyzeSurvey_Button.ButtonPushedFcn = createCallbackFcn(app, @ProcessTab_AnalyzeSurvey_Button_Pushed, true);
            app.ProcessTab_AnalyzeSurvey_Button.Text = 'Analyze survey';
            app.ProcessTab_Clear_Button = uibutton(app.ProcessTab_SurveyProcessing_GridLayout, 'push');
            app.ProcessTab_Clear_Button.ButtonPushedFcn = createCallbackFcn(app, @ProcessTab_Clear_Button_Pushed, true);
            app.ProcessTab_Clear_Button.Text = 'Clear';
            app.ProcessTab_Edit_Button = uibutton(app.ProcessTab_SurveyProcessing_GridLayout, 'push');
            app.ProcessTab_Edit_Button.ButtonPushedFcn = createCallbackFcn(app, @ProcessTab_Edit_Button_Pushed, true);
            app.ProcessTab_Edit_Button.Text = {'Edit'; ''};
            app.ProcessTab_Draw_Button = uibutton(app.ProcessTab_SurveyProcessing_GridLayout, 'push');
            app.ProcessTab_Draw_Button.ButtonPushedFcn = createCallbackFcn(app, @ProcessTab_Draw_Button_Pushed, true);
            app.ProcessTab_Draw_Button.Text = 'Draw';
            app.ProcessTab_File_Button = uibutton(app.ProcessTab_SurveyProcessing_GridLayout, 'push');
            app.ProcessTab_File_Button.ButtonPushedFcn = createCallbackFcn(app, @ProcessTab_File_Button_Pushed, true);
            app.ProcessTab_File_Button.Text = 'File';
            app.ProcessTab_SurveyLimits_Label = uilabel(app.ProcessTab_SurveyProcessing_GridLayout);
            app.ProcessTab_SurveyLimits_Label.Text = 'Survey limits:';
            app.ProcessTab_LineProcessing_Panel = uipanel(app.ProcessTab_GridLayout);
            app.ProcessTab_LineProcessing_Panel.Title = 'Line processing';
            app.ProcessTab_LineProcessing_GridLayout = uigridlayout(app.ProcessTab_LineProcessing_Panel);
            app.ProcessTab_ShowLineAnalysis_CheckBox = uicheckbox(app.ProcessTab_LineProcessing_GridLayout);
            app.ProcessTab_ShowLineAnalysis_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessTab_ShowLineAnalysis_CheckBox_ValueChanged, true);
            app.ProcessTab_ShowLineAnalysis_CheckBox.Text = 'Show line analysis';
            app.GridsizemLabel = uilabel(app.ProcessTab_LineProcessing_GridLayout);
            app.GridsizemLabel.Text = 'Grid size (m):';
            app.ProcessTab_GriddedDataDisplayed_DropDownMenu = uidropdown(app.ProcessTab_LineProcessing_GridLayout);
            app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Items = {'-', 'Backscatter', 'Bathymetry', 'Slope', 'Mode'};
            app.ProcessTab_GriddedDataDisplayed_DropDownMenu.ValueChangedFcn = createCallbackFcn(app, @ProcessTab_GriddedDataDisplayed_DropDownMenu_ValueChanged, true);
            app.ProcessTab_GriddedDataDisplayed_DropDownMenu.Value = '-';
            app.ProcessTab_Analyze_Button = uibutton(app.ProcessTab_LineProcessing_GridLayout, 'push');
            app.ProcessTab_Analyze_Button.ButtonPushedFcn = createCallbackFcn(app, @ProcessTab_Analyze_Button_Pushed, true);
            app.ProcessTab_Analyze_Button.Text = 'Analyze';
            app.ProcessTab_Grid_Button = uibutton(app.ProcessTab_LineProcessing_GridLayout, 'push');
            app.ProcessTab_Grid_Button.ButtonPushedFcn = createCallbackFcn(app, @ProcessTab_Grid_Button_Pushed, true);
            app.ProcessTab_Grid_Button.Text = 'Grid';
            app.GriddeddatadisplayedDropDownLabel = uilabel(app.ProcessTab_LineProcessing_GridLayout);
            app.GriddeddatadisplayedDropDownLabel.Text = 'Gridded data displayed:';
            app.ProcessTab_GridSize_EditField = uieditfield(app.ProcessTab_LineProcessing_GridLayout, 'numeric');
            app.ProcessTab_GridSize_EditField.Limits = [0 Inf];
            app.ProcessTab_GridSize_EditField.Value = 1;
            app.Main_Axes = uiaxes(app.MainFigure_GridLayout);
            zlabel(app.Main_Axes, 'Z')
            app.Main_Axes.XTickLabel = '';
            app.Main_Axes.YTickLabel = '';
            app.Main_Axes.Tag = 'MainAxes';
            app.Survey_Axes = uiaxes(app.MainFigure_GridLayout);
            app.Survey_Axes.XTickLabel = '';
            app.Survey_Axes.YTickLabel = '';
            app.Survey_Axes.Visible = 'off';
            app.Survey_Axes.Tag = 'SurveyResultAxes';
            app.FilesTab_ContextMenu = uicontextmenu(app.MainFigure);
            % Assign app.FilesTab_ContextMenu
            app.FilesTab_Table.ContextMenu = app.FilesTab_ContextMenu;
            app.FilesTab_SelectAll_Menu = uimenu(app.FilesTab_ContextMenu);
            app.FilesTab_SelectAll_Menu.MenuSelectedFcn = createCallbackFcn(app, @FilesTab_SelectAll_Menu_Selected, true);
            app.FilesTab_SelectAll_Menu.Text = 'Select all files';
            app.FilesTab_SelectConverted_Menu = uimenu(app.FilesTab_ContextMenu);
            app.FilesTab_SelectConverted_Menu.MenuSelectedFcn = createCallbackFcn(app, @FilesTab_SelectConverted_Menu_Selected, true);
            app.FilesTab_SelectConverted_Menu.Text = 'Select converted files';
            app.FilesTab_DeselectAll_Menu = uimenu(app.FilesTab_ContextMenu);
            app.FilesTab_DeselectAll_Menu.MenuSelectedFcn = createCallbackFcn(app, @FilesTab_DeselectAll_Menu_Selected, true);
            app.FilesTab_DeselectAll_Menu.Text = 'Deselect all files';
            app.FilesTab_InverseSelection_Menu = uimenu(app.FilesTab_ContextMenu);
            app.FilesTab_InverseSelection_Menu.MenuSelectedFcn = createCallbackFcn(app, @FilesTab_InverseSelection_Menu_Selected, true);
            app.FilesTab_InverseSelection_Menu.Text = 'Inverse selection';
            app.LinesTab_ContextMenu = uicontextmenu(app.MainFigure);
            % Assign app.LinesTab_ContextMenu
            app.LinesTab_ListBox.ContextMenu = app.LinesTab_ContextMenu;
            app.LinesTab_UnloadSelected_Menu = uimenu(app.LinesTab_ContextMenu);
            app.LinesTab_UnloadSelected_Menu.MenuSelectedFcn = createCallbackFcn(app, @LinesTab_UnloadSelected_Menu_Selected, true);
            app.LinesTab_UnloadSelected_Menu.Text = 'Unload selected lines';
            app.LinesTab_SortAllLines_Menu = uimenu(app.LinesTab_ContextMenu);
            app.LinesTab_SortAllLines_Menu.Text = 'Sort all lines';
            app.LinesTab_MostRecentLinesOnTop_Menu = uimenu(app.LinesTab_SortAllLines_Menu);
            app.LinesTab_MostRecentLinesOnTop_Menu.MenuSelectedFcn = createCallbackFcn(app, @LinesTab_MostRecentLinesOnTop_Menu_Selected, true);
            app.LinesTab_MostRecentLinesOnTop_Menu.Text = 'most recent lines on top';
            app.LinesTab_OldestLinesOnTop_Menu = uimenu(app.LinesTab_SortAllLines_Menu);
            app.LinesTab_OldestLinesOnTop_Menu.MenuSelectedFcn = createCallbackFcn(app, @LinesTab_OldestLinesOnTop_Menu_Selected, true);
            app.LinesTab_OldestLinesOnTop_Menu.Text = 'oldest lines on top';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Iskaffe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end