classdef Ristretto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                     matlab.ui.Figure
        MainFigureGrid                 matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        RawFilesTab                    matlab.ui.container.Tab
        RFTGridLayout                  matlab.ui.container.GridLayout
        RFTTable                       matlab.ui.control.Table
        RFTFolderGridLayout            matlab.ui.container.GridLayout
        EditFieldLabel                 matlab.ui.control.Label
        RFTFolderEditField             matlab.ui.control.EditField
        RFTFolderButton                matlab.ui.control.Button
        RFTButtonsGridLayout           matlab.ui.container.GridLayout
        RFTButtonConvert               matlab.ui.control.Button
        RTFButtonLoad                  matlab.ui.control.Button
        LoadedLinesTab                 matlab.ui.container.Tab
        ProcessTab                     matlab.ui.container.Tab
        ProcessGrid                    matlab.ui.container.GridLayout
        ProcessingPanel                matlab.ui.container.Panel
        ProcessingGrid                 matlab.ui.container.GridLayout
        GridButton                     matlab.ui.control.Button
        GridresolutionmEditFieldLabel  matlab.ui.control.Label
        GridresolutionmEditField       matlab.ui.control.NumericEditField
        AnalyzeButton                  matlab.ui.control.Button
        DisplayPanel                   matlab.ui.container.Panel
        DisplayGrid                    matlab.ui.container.GridLayout
        DataDisplayedDropDownLabel     matlab.ui.control.Label
        DataDisplayedDropDown          matlab.ui.control.DropDown
        ShowHideAnalysisResultsButton  matlab.ui.control.StateButton
        AnalysisResultsPanel           matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        Map                            matlab.ui.control.UIAxes
        RFTContextMenu                 matlab.ui.container.ContextMenu
        SelectallMenu                  matlab.ui.container.Menu
        DeselectallMenu                matlab.ui.container.Menu
        InverseselectionMenu           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        % general properties
        CoffeeCodeRootFolder % Description
        FDataVersion
        FData = [];
        
        % raw data properties
        rawDataFilesList = [];
        idxConverted
        idxLoaded
        
        % configuration
        datagramSource = 'X8';
        ellips = '';
        tmproj = '';
        
    end
    
    
    methods (Access = private)
        
        % Update Raw Files Tab
        function updateRawFilesTable(app,keepSelection)
            
            % save list of files selected
            if ~isempty(app.RFTTable.Data)
                idxFilesSelected = cell2mat(app.RFTTable.Data(:,1));
            end
            
            % list raw_files in search path
            rawDataFolder = app.RFTFolderEditField.Value;
            app.rawDataFilesList = CFF_list_raw_files_in_dir(rawDataFolder);
            
            % check which are already converted
            app.idxConverted = CFF_are_raw_files_converted(app.rawDataFilesList);
            
            % check which are currently loaded
            app.idxLoaded = CFF_are_raw_files_loaded(app.rawDataFilesList, app.FData);
            
            % prepare array
            n_rawfiles = numel(app.rawDataFilesList);
            [disp_folder, filename, ext] = CFF_fileparts_as_cell(CFF_onerawfileonly(app.rawDataFilesList));
            disp_files = strcat(filename, ext);
            for ii = 1:n_rawfiles
                if iscell(app.rawDataFilesList{ii})
                    % paired file
                    disp_files{ii} = strcat(disp_files{ii}, ' (paired)');
                end
            end
            new_entry = [num2cell(false(n_rawfiles, 1)), disp_files, disp_folder];
            
            % update table data
            app.RFTTable.Data = new_entry;
            
            % set files colors depending on status
            removeStyle(app.RFTTable);
            if ~isempty(new_entry)
                app.RFTTable.Visible = 'off';
                idx2subs = @(idx) cat(2,find([idx;0]),2.*ones(numel(find(idx)),1)); % function to turn files idx to table subscript indices
                styleNotConverted = uistyle('FontColor',[0.5 0.5 0.5]); % gray
                addStyle(app.RFTTable,styleNotConverted,'cell',idx2subs(~app.idxConverted));
                styleConvertedNotLoaded = uistyle('FontWeight','bold','FontColor','k'); % black
                addStyle(app.RFTTable,styleConvertedNotLoaded,'cell',idx2subs(app.idxConverted&~app.idxLoaded));
                styleLoaded = uistyle('FontWeight','bold','FontColor',[0.4660 0.6740 0.1880]); % green
                addStyle(app.RFTTable,styleLoaded,'cell',idx2subs(app.idxConverted&app.idxLoaded));
                app.RFTTable.Visible = 'on';
            end
            
            % re-select files selected
            if exist('keepSelection','var') && keepSelection==1 && ~isempty(idxFilesSelected)
                app.RFTTable.Visible = 'off';
                app.RFTTable.Data(idxFilesSelected,1) = {true};
                app.RFTTable.Visible = 'on';
            end
            
        end
        
        % Update Raw Files Tab
        function updateMap(app)
            
            % for test of pulse mode display
            % app.FData{1}.Ru_1D_PulseLength(1:50) = categorical(ones(1,50),1,{'Long CW'});
            % app.FData{2}.Ru_1D_PulseLength(1:100) = categorical(ones(1,100),1,{'Medium CW'});
            
            
            % index of lines to show
            nLines = numel(app.FData);
            
            if nLines == 0
                return
            end
            
            nav_col = [0 0 0]; % black
            
            % (re)initialize colorbar
            colormap('default')
            colorbar('off')
            colorbarOn = 0;
            
            % (re)initialize xlim and ylim for zoom extent
            xlim = [nan nan];
            ylim = [nan nan];
            cax  = [nan nan];
            
            % work line per line
            for ii = 1:nLines
                
                % get data
                fData = app.FData{ii};
                
                % Line ID
                lineID = num2str(fData.ID,'%.0f');
                
                
                [~,fname,ext] = fileparts(fData.ALLfilename{1});
                lineName  = string([fname ext]);
                
                
                %% NAVIGATION
                lineNavID = [lineID '_nav'];
                
                % draw line navigation
                handle_plot_1 = plot(app.Map,fData.X_1P_pingE,fData.X_1P_pingN,...
                    'Tag',lineNavID,...
                    'Visible','on',...
                    'Color',nav_col);
                %'ButtonDownFcn',{@disp_wc_ping_cback,main_figure},...
                
                filename = string(CFF_onerawfileonly(fData.ALLfilename));
                
                [~,idx] = nanmin(abs(fData.X8_BP_AcrosstrackDistanceY),[],'linear');
                nadirDepth = fData.X8_BP_DepthZ(idx);
                
                dtrow = [dataTipTextRow(lineName,''),...
                    dataTipTextRow('Easting (m)','XData'),...
                    dataTipTextRow('Northing (m)','YData'),...
                    dataTipTextRow('Depth (m)',nadirDepth,'%.2f')];
                handle_plot_1.DataTipTemplate.DataTipRows = dtrow;
                handle_plot_1.DataTipTemplate.Interpreter = 'none';
                
                % draw points every dt seconds as subsampled navigation
                dt = 60; % in seconds
                pingTimeSec = 0.001.*(fData.X_1P_pingTSMIM-fData.X_1P_pingTSMIM(1));
                idx_f = mod(ceil(pingTimeSec),dt)==0 & [0,diff(ceil(pingTimeSec))];
                
                plot(app.Map,fData.X_1P_pingE(idx_f),fData.X_1P_pingN(idx_f),'.',...
                    'Tag',lineNavID,...
                    'Visible','on',...
                    'Color',nav_col);
                %'ButtonDownFcn',{@disp_wc_ping_cback,main_figure},...
                %'UserData',user_data);
                
                %                 % draw circle as start of line
                %                 plot(app.Map,fData.X_1P_pingE(1),fData.X_1P_pingN(1),'o',...
                %                     'Tag',lineNavID,...
                %                     'Visible','on',...
                %                     'Color',nav_col);
                %
                %                 % end of line
                %                 plot(app.Map,fData.X_1P_pingE(end),fData.X_1P_pingN(end),'s',...
                %                     'Tag',lineNavID,...
                %                     'Visible','on',...
                %                     'Color',nav_col);
                
                
                % triangle at start of line
                L = 0.5*nanmean(fData.X8_BP_DepthZ(:,1)); % in m
                h = atan2d( (fData.X_1P_pingE(2)-fData.X_1P_pingE(1)),(fData.X_1P_pingN(2)-fData.X_1P_pingN(1)) ); % heading in deg
                lineStartX = fData.X_1P_pingE(1) + [-0.5*L*cosd(h),0.5*L*cosd(h),0.5*L*sind(h),-0.5*L*cosd(h)];
                lineStartY = fData.X_1P_pingN(1) + [0.5*L*sind(h),-0.5*L*sind(h),0.5*L*cosd(h),0.5*L*sind(h)];
                plot(app.Map,lineStartX,lineStartY,...
                    'Tag',lineNavID,...
                    'Visible','on',...
                    'Color',nav_col);
                
                % segment at end of line
                L = 0.5*nanmean(fData.X8_BP_DepthZ(:,end));
                h = atan2d( (fData.X_1P_pingE(end)-fData.X_1P_pingE(end-1)),(fData.X_1P_pingN(end)-fData.X_1P_pingN(end-1)) );
                lineStartX = fData.X_1P_pingE(end) + [-0.5*L*cosd(h),0.5*L*cosd(h)];
                lineStartY = fData.X_1P_pingN(end) + [0.5*L*sind(h),-0.5*L*sind(h)];
                plot(app.Map,lineStartX,lineStartY,...
                    'Tag',lineNavID,...
                    'Visible','on',...
                    'Color',nav_col);
                
                % Update zoom extents
                xlim(1) = nanmin(xlim(1),nanmin(fData.X_1P_pingE(:)));
                xlim(2) = nanmax(xlim(2),nanmax(fData.X_1P_pingE(:)));
                ylim(1) = nanmin(ylim(1),nanmin(fData.X_1P_pingN(:)));
                ylim(2) = nanmax(ylim(2),nanmax(fData.X_1P_pingN(:)));
                
                
                %% GRIDDED DATA
                
                % flag for existence of data in this line
                lineDataExist = isfield(fData,'X_NE_bathy');
                
                if lineDataExist
                    
                    % ID for data objects
                    lineDatID = [lineID '_dat'];
                    
                    % search for existing displayed data objects
                    lineDatObj = findobj(app.Map,'Tag',lineDatID);
                    
                    % delete them
                    delete(lineDatObj);
                    lineDatObj = [];
                    
                    % set data to display
                    switch app.DataDisplayedDropDown.Value
                        case 'Bathy'
                            data = fData.X_NE_bathy;
                            alphaData = ~isnan(data);
                            % adjust colour scale
                            cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
                        case 'BS'
                            data = fData.X_NE_bs;
                            alphaData = ~isnan(data);
                            % adjust colour scale
                            cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
                        case 'Slope'
                            data = fData.X_NE_slope;
                            alphaData = ~isnan(data);
                            % adjust colour scale
                            cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
                        case 'Pulse Mode'
                            
                            allCats = cellfun(@(x) categories(x.Ru_1D_PulseLength),app.FData,'UniformOutput',false);
                            allCats = unique(cat(1,allCats{:}));
                            
                            [~,Locb] = ismember(fData.Ru_1D_PulseLength, allCats);
                            
                            indTemp = fData.X_NE_indexInRu_1D;
                            iNaN = isnan(indTemp);
                            indTemp(iNaN) = 1; % temporary
                            data = Locb(indTemp);
                            data(iNaN) = NaN;
                            alphaData = ~isnan(data);
                            
                            % adjust colour scale
                            cax = [0.5 numel(allCats)+0.5];
                            
                    end
                    
                    % display data
                    lineDatObj = imagesc(app.Map,...
                        fData.X_1E_2DgridEasting,...
                        fData.X_N1_2DgridNorthing,...
                        data,...
                        'Visible','on',...
                        'Tag',lineDatID,...s
                        'AlphaData',alphaData);
                    
                    colorbarOn = 1;
                    
                    % push grid to the bottom of the display stack so
                    % navigation stays on top
                    ch = get(app.Map,'children'); % get all plots from app.Map
                    [~,neworder] = sort(ismember(ch,lineDatObj)); % reorder the handles to move the background lines to the background
                    set(app.Map,'children',ch(neworder)); % set the new order of all lines
                    
                    % adjust colour scale
                    cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
                    
                    % Adjust zoom extents
                    xlim(1) = nanmin(xlim(1),nanmin(fData.X_1E_2DgridEasting(:)));
                    xlim(2) = nanmax(xlim(2),nanmax(fData.X_1E_2DgridEasting(:)));
                    ylim(1) = nanmin(ylim(1),nanmin(fData.X_N1_2DgridNorthing(:)));
                    ylim(2) = nanmax(ylim(2),nanmax(fData.X_N1_2DgridNorthing(:)));
                    
                end
                
                
                %% BAD DATA
                
                % delete existing objects
                lineBadDataID = [lineID '_bad'];
                lineBadDataObj = findobj(app.Map,'Tag',lineBadDataID);
                delete(lineBadDataObj);

                    if isfield(app.FData{ii},'X_BP_goodData')
                        
                        idxBad = ~app.FData{ii}.X_BP_goodData;
                        
                        plot(app.Map,...
                            app.FData{ii}.X_BP_bottomEasting(idxBad),...
                            app.FData{ii}.X_BP_bottomNorthing(idxBad),...
                            'Tag',lineBadDataID,...
                            'Visible','on',...
                            'LineStyle','none',...
                            'Color','yellow',...
                            'Marker','*');
                    end
                    
                    if isfield(app.FData{ii},'X_1P_badPing')
                        
                        idxBad = app.FData{ii}.X_1P_badPing;
                        
                        plot(app.Map,...
                            app.FData{ii}.X_BP_bottomEasting(:,idxBad),...
                            app.FData{ii}.X_BP_bottomNorthing(:,idxBad),...
                            'Tag',lineBadDataID,...
                            'Visible','on',...
                            'LineStyle','none',...
                            'Color','red',...
                            'Marker','*');
                        
                    end
                    
                    if isfield(app.FData{ii},'X_1P_toResurvey')
                        
                        % indices of patches to resurvey
                        idx = app.FData{ii}.X_1P_toResurvey;
                        idxStart = find(diff([0,idx])==1)';
                        idxEnd = find(diff([idx,0])==-1)';
                        idx2 = [idxStart idxEnd];
                        
                        nPol = size(idx2,1);
                        
                        for jj = 1:nPol
                            
                            id = unique(round(linspace(idx2(jj,1),idx2(jj,2),10)));
                            
                            stbE = app.FData{ii}.X_BP_bottomEasting(1,id);
                            prtE = app.FData{ii}.X_BP_bottomEasting(end,id);
                            stbN = app.FData{ii}.X_BP_bottomNorthing(1,id);
                            prtN = app.FData{ii}.X_BP_bottomNorthing(end,id);
                            
                            pgon = polyshape([stbE fliplr(prtE)],[stbN fliplr(prtN)]);
                            plot(app.Map,...
                                pgon,...
                                'Tag',lineBadDataID,...
                                'Visible','on',...
                                'FaceColor','red',...
                                'FaceAlpha',0.2)
                            
                        end
                        
                    end

                
            end
            
            % Set map view
            set(app.Map,'YLim',ylim,'XLim',xlim);
            app.equalizeMapView(1.2);
            
            % set colorbar and colormap
            if colorbarOn
                cb = colorbar;
                
                % set data to display
                switch app.DataDisplayedDropDown.Value
                    case 'Bathy'
                        colormap(jet);
                    case 'BS'
                        colormap(gray);
                    case 'Slope'
                        colormap(gray);
                    case 'Pulse Mode'
                        if numel(allCats) ==1
                            colormap([jet(1);jet(1)]);
                        else
                            colormap(jet(numel(allCats)));
                        end
                        set(cb,'ytick',1:numel(allCats),'yticklabel',allCats)
                end
            end
            
            % color scale adjust
            if all(~isnan(cax))
                caxis(app.Map,cax);
            end
            
        end
        
        function equalizeMapView(app, bufferRatio)
            % This function adjusts the xlim and ylim properties of the map
            % to ensure the map maintains equal units of m/pixel in X and Y
            % so the data never looks stretched.
            % This is called after those properties are changed, but also
            % automatically after every zoom.
            
            % these are the current extents of the data view, in
            % easting/northing (meters)
            xlim = get(app.Map,'XLim');
            ylim = get(app.Map,'YLim');
            
            % The adjustment depends on how the "aspect ratio" (ratio of
            % height to width) of the data view compare to that
            % of the map frame.
            % If the map frame is "flatter" than the data view, we will fit
            % the height of the data view to the map frame and then adjust
            % automatically in width.
            % If the data view is "flatter" than the map frame, we will fit
            % the width of the data view to the map frame and then adjust
            % automatically in height.
            
            % aspect ratio of the data view
            reqAspectRatio = diff(ylim)/diff(xlim);
            
            % aspect ratio of the map frame
            pos = getpixelposition(app.Map);
            mapAspectRatio = pos(4)/pos(3);
            
            % Instead of fitting the data view to the data frame exactly,
            % we allow in input some extra buffer. e.g. a value of 1.2
            % would mean that the frame width will represent 120% of the
            % data view width (if fitting in width). This way we "zoom-out"
            % a bit more compare to the original view. By default, we fit
            % exactly using a ratio of 1.
            if ~exist('bufferRatio','var')
                bufferRatio = 1;
            end
            
            if reqAspectRatio > mapAspectRatio
                % map frame is "flatter" than data view
                % -> Define ylim to fit the data in height
                ylim_new = [-diff(ylim),diff(ylim)]*bufferRatio/2 + ylim(1) + diff(ylim)/2;
                % X distance to ensure equal m per pixel in X and Y
                dx = diff(ylim_new)/mapAspectRatio;
                % define xlim with old view centeredd
                xlim_new = [-dx/2,dx/2] + xlim(1) + diff(xlim)/2;
            else
                % data view is "flatter" than map frame
                % -> Define xlim to fit the data in width
                xlim_new = [-diff(xlim),diff(xlim)]*bufferRatio/2 + xlim(1) + diff(xlim)/2;
                % Y distance to ensure equal m per pixel in X and Y
                dy = diff(xlim_new)*mapAspectRatio;
                % define ylim with old view centered
                ylim_new = [-dy/2,dy/2] + ylim(1) + diff(ylim)/2;
            end
            
            % set those new values to map
            set(app.Map,'YLim',ylim_new,'XLim',xlim_new);
            
        end
        
        function updateAnalysisResultsLabel(app)
            
            nLines = numel(app.FData);
            txt1 = sprintf('Number of lines loaded: %i', nLines);
            
            nSoundings = 0;
            nBadSoundings = 0;
            nPings = 0;
            nBadPings = 0;
            nPatchesToResurvey = 0;
            for ii=1:nLines
                
                % number of beams and pings
                [iiBeams, iiPings] = size(app.FData{ii}.X_BP_goodData);
                nPings = nPings + iiPings;
                nSoundings = nSoundings + iiBeams.*iiPings;
                
                % number of bad pings and bad beams
                nBadSoundings = nBadSoundings + sum(sum(~app.FData{ii}.X_BP_goodData));
                nBadPings = nBadPings + sum(app.FData{ii}.X_1P_badPing);
                
                % number of patches to resurvey
                idx = app.FData{ii}.X_1P_toResurvey;
                idxStart = find(diff([0,idx])==1)';
                idxEnd = find(diff([idx,0])==-1)';
                idx2 = [idxStart idxEnd];
                nPatchesToResurvey = nPatchesToResurvey + size(idx2,1);
            end
            
            txt2 = sprintf('Bad soundings: %i/%i (%.2f %%)', nBadSoundings, nSoundings, nBadSoundings.*100./nSoundings);
            txt3 = sprintf('Bad pings: %i/%i (%.2f %%)', nBadPings, nPings, nBadPings.*100./nPings);            
            txt4 = sprintf('Number of patches to re-survey: %i', nPatchesToResurvey);
            
            
            app.Label.Text = sprintf('%s\n%s\n%s\n%s',txt1,txt2,txt3,txt4);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Make figure invisible while we finish the visual setup
            app.MainFigure.Visible = 'off';
            
            % Main figure icon
            app.MainFigure.Icon = 'Espresso.png';
            
            % Resize and reposition figure according to monitor's dimension
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            app.MainFigure.Position = [left bottom width height];
            
            % Set default folder location
            app.RFTFolderEditField.Value = cd;
            
            % Make figure visible, the non-visual can happen after
            app.MainFigure.Visible = 'on';
            
            % Add Ristretto folder to path
            RistrettoRootFolder = fileparts(which('Ristretto'));
            addpath(genpath(RistrettoRootFolder));
            
            % Add Coffee code to path
            app.CoffeeCodeRootFolder = 'C:\Users\Schimel_Alexandre\Code\MATLAB\Espresso\toolboxes\coffee';
            if ~isfolder(app.CoffeeCodeRootFolder)
                % Throw an alert to find manually a suitable
                % coffee folder
                app.CoffeeCodeRootFolder = uigetdir(RistrettoRootFolder,'Select suitable CoFFee folder');
            end
            % Add to path
            addpath(genpath(app.CoffeeCodeRootFolder));
            app.FDataVersion = CFF_get_current_fData_version();
            
            % Customize the maps' available built-in interactions (i.e.
            % those available when NOT using the map's toolbar buttons)
            app.Map.Interactions = [panInteraction zoomInteraction dataTipInteraction];
            
            % Customize the map's toolbar
            enableLegacyExplorationModes(app.MainFigure);
            tb = axtoolbar(app.Map,{'export','pan','zoomin','zoomout','restoreview'});
            
            % Add the equalizeMapView function as a post-zoom callback to
            % ensure we maintain equal aspect ratio
            h = zoom(app.MainFigure);
            h.ActionPostCallback = @(o,e) app.equalizeMapView();
            
            % Initialize Raw Files table
            app.updateRawFilesTable();
            
            % Initialize Map
            app.updateMap();
            
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Value changed function: RFTFolderEditField
        function RFTFolderEditFieldValueChanged(app, event)
            % Check if value is an existing folder
            requestedFolder = app.RFTFolderEditField.Value;
            if ~isfolder(requestedFolder)
                % Error dialog box
                uialert(app.MainFigure,'Folder not found','Invalid Folder','CloseFcn','uiresume(gcbf)');
                uiwait(app.MainFigure)
                % Reset previous field value
                app.RFTFolderEditField.Value = event.PreviousValue;
            else
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end

        % Button pushed function: RFTFolderButton
        function RFTFolderButtonPushed(app, event)
            % Open folder selection dialog box
            currentFolder = app.RFTFolderEditField.Value;
            newFolder = uigetdir(currentFolder,'Select folder of raw data files');
            figure(app.MainFigure); % uigetdir sometimes puts app in the background. Bring it back on top
            if newFolder ~= 0
                % Update value of the FolderEditField
                app.RFTFolderEditField.Value = newFolder;
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end

        % Button pushed function: RFTButtonConvert
        function RFTButtonConvertPushed(app, event)
            % Get idx of selected files
            idxFilesToConvert = cell2mat(app.RFTTable.Data(:,1));
            % Default value to be overwritten if relevant
            forceReconvert = 0;
            % Prompt if some files already converted
            if any(idxFilesToConvert.*app.idxConverted)
                N = sum(idxFilesToConvert.*app.idxConverted);
                if N == 1
                    questDlgTxt = sprintf('1 file for which conversion was requested is already converted.\n\nDo you want to re-convert it?');
                else
                    questDlgTxt = sprintf('%i files for which conversion was requested are already converted.\n\nDo you want to re-convert them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Raw Files Conversion', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        forceReconvert = 1;
                    case 'No'
                        forceReconvert = 0;
                    case 'Cancel'
                        % abort converting
                        return
                end
            end
            % Get filenames
            rawFilesList = app.rawDataFilesList(idxFilesToConvert);
            % Convert files
            CFF_convert_raw_files(rawFilesList,...
                'conversionType','seafloor',...
                'saveFDataToDrive',1,...
                'forceReconvert',forceReconvert,...
                'outputFData',0,...
                'abortOnError',0,...
                'convertEvenIfDtgrmsMissing',0,...
                'dr_sub',1,...
                'db_sub',1,...
                'comms','multilines');
            
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
        end

        % Button pushed function: RTFButtonLoad
        function RTFButtonLoadPushed(app, event)
            % Get idx of selected files
            idxFilesSelected = cell2mat(app.RFTTable.Data(:,1));
            idxFilesToLoad = idxFilesSelected & ~app.idxLoaded;
            % Idx of files requested but not converted yet
            idxNeedConversionFirst = idxFilesToLoad & ~app.idxConverted;
            % Prompt if some files not already converted
            if any(idxNeedConversionFirst)
                N = sum(idxNeedConversionFirst);
                if N == 1
                    questDlgTxt = sprintf('1 file for which loading was requested has not been converted yet.\n\nDo you want to convert it and load it?');
                else
                    questDlgTxt = sprintf('%i files for which loading was requested have not been converted yet.\n\nDo you want to convert them and load them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Converted Files Loading', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        % Convert those files
                        CFF_convert_raw_files(app.rawDataFilesList(idxNeedConversionFirst),...
                            'conversionType','seafloor',...
                            'saveFDataToDrive',1,...
                            'forceReconvert',0,...
                            'outputFData',0,...
                            'abortOnError',0,...
                            'convertEvenIfDtgrmsMissing',0,...
                            'dr_sub',1,...
                            'db_sub',1,...
                            'comms','multilines');
                        % Update Raw Files Table contents
                        app.updateRawFilesTable(1);
                    case 'No'
                        % Remove non-converted files from loading list
                        idxFilesToLoad = logical(idxFilesToLoad.*app.idxConverted);
                    case 'Cancel'
                        % abort loading
                        return
                end
            end
            % Get filenames
            toLoadFilesList = app.rawDataFilesList(idxFilesToLoad);
            if isempty(toLoadFilesList)
                return
            end
            % Geoprocess files
            fData_new = CFF_geoprocess_files(toLoadFilesList,...
                'datagramSource',app.datagramSource,...
                'ellips',app.ellips,...
                'tmproj',app.tmproj,...
                'saveFDataToDrive',0,...
                'outputFData',1,...
                'abortOnError',0,...
                'comms','multilines');
            % by default, CFF_geoprocess_files outputs a struct for a
            % single file. Turn to cell array if a single file was loaded
            if isstruct(fData_new)
                fData_new = {fData_new};
            end
            % update app's geoprocessing parameters
            if isempty(app.tmproj)
                app.ellips = fData_new{1}.MET_ellips;
                app.tmproj = fData_new{1}.MET_tmproj;
            end
            if isempty(app.datagramSource)
                app.datagramSource = fData_new{1}.MET_datagramSource;
            end
            % Fix fData paths if necessary
            fData_new = CFF_fix_fData_paths(fData_new, toLoadFilesList);
            % Time-tag the individual fData structs
            for iF = 1:numel(fData_new)
                fData_new{iF}.ID = str2double(datestr(now,'yyyymmddHHMMSSFFF'));
                pause(1e-2); % pause here to ensure unique IDs
            end
            % add new fData to old one
            app.FData = [app.FData,fData_new];
            % Update Map
            updateMap(app);
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
        end

        % Menu selected function: SelectallMenu
        function SelectallMenuSelected(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {true}, app.RFTTable.Data(:,1));
        end

        % Menu selected function: DeselectallMenu
        function DeselectallMenuSelected(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {false}, app.RFTTable.Data(:,1));
        end

        % Menu selected function: InverseselectionMenu
        function InverseselectionMenuSelected(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {~x}, app.RFTTable.Data(:,1));
        end

        % Button pushed function: GridButton
        function GridButtonPushed(app, event)
            
            if isempty(app.FData)
                return
            end
            
            % init
            res = app.GridresolutionmEditField.Value;
            
            % do the thing
            app.FData = CFF_grid_lines(app.FData,'res',res, ...
                'comms','multilines');
            
            % Update Map
            updateMap(app);
            
        end

        % Value changed function: DataDisplayedDropDown
        function DataDisplayedDropDownValueChanged(app, event)
            
            if isempty(app.FData)
                return
            end
            
            % Update Map
            updateMap(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            
            if isempty(app.FData)
                return
            end
            
            idx_fData = 1:numel(app.FData);
            nFiles = numel(idx_fData);
            
            % Process per file
            for ii = idx_fData(:)'
                
                %% bad soundings
                
                % criteria 1: identifying bad beams from detection info
                [DetectInfo, ~] = CFF_decode_X8_DetectionInfo(app.FData{ii}.X8_BP_DetectionInformation);
                badSoundings1 = DetectInfo>1;
                
                app.FData{ii}.X_BP_goodData = ~badSoundings1;
                
                %% bad pings
                
                nBeams = size(DetectInfo,1);
                
                % criteria 1: a ping with a percentage of bad soundings
                % exceeding a threshold is a bad ping
                thr = 0.1;
                badPings1 = (sum(badSoundings1)./nBeams) > thr;
                
                % criteria2: a ping which average backscatter level is
                % abnormally low is a bad ping
                
                
                avgBS = median(app.FData{ii}.X8_BP_ReflectivityBS);
                nPings = max(size(avgBS));
                
                iKeep = zeros(1,nPings);
                lowth = nan(1,nPings);
                
                trailNum = 20;
                iKeep(1:trailNum) = 1;
                
                iKeep(1:trailNum) = 1;
                
                for jj = trailNum+1:nPings
                    
                    idx = find(iKeep,trailNum,'last');
                    dat = avgBS(idx);
                    lowth(jj) = median(dat) - 2.*range(dat);

                    if (avgBS(jj)<lowth(jj)) || badPings1(jj) 
                        iKeep(jj) = 0;
                    else
                        iKeep(jj) = 1;
                    end
                   
                end
                
                badPings = ~iKeep;
                
                figure;
                tiledlayout(2,1);
                ax1 = nexttile; 
                imagesc(app.FData{ii}.X8_BP_ReflectivityBS);
                colormap gray; caxis([-400 0]);grid on
                hold on
                if any(badPings)
                    plot(find(badPings),400,'r*')
                end
                ax2 = nexttile;
                plot(avgBS,'.-'); hold on
                plot(lowth,'r')
                plot(find(~iKeep),avgBS(~iKeep),'r*')
                grid on
                linkaxes(findall(gcf,'type','axes'),'x');
                
                app.FData{ii}.X_1P_badPing = badPings;
                
                % Sliding window size in num of pings, and threshold
                % number of bad pings within a window above which to
                % classify window as to be resurveyed
                win = 100;
                nThr = 1;
                
                app.FData{ii}.X_1P_toResurvey = conv2(app.FData{ii}.X_1P_badPing,ones(1,win),'same')>=nThr;
                
            end
            
            % toggle on Show analysis button
            app.ShowHideAnalysisResultsButton.Value = 1;
            
            % Update Map
            updateMap(app);
            
            % Update analysis results label
            updateAnalysisResultsLabel(app);
            
        end

        % Value changed function: ShowHideAnalysisResultsButton
        function ShowHideAnalysisResultsButtonValueChanged(app, event)
            
            value = app.ShowHideAnalysisResultsButton.Value;
            
            nLines = numel(app.FData);
            for ii = 1:nLines
                lineID = num2str(app.FData{ii}.ID,'%.0f');
                lineBadDataID = [lineID '_bad'];
                lineBadDataObj = findall(app.Map,'Tag',lineBadDataID);
                set(lineBadDataObj,'Visible',value);
            end
                
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Ristretto';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.MainFigureGrid = uigridlayout(app.MainFigure);
            app.TabGroup = uitabgroup(app.MainFigureGrid);
            app.RawFilesTab = uitab(app.TabGroup);
            app.RawFilesTab.Title = 'Raw files';
            app.RFTGridLayout = uigridlayout(app.RawFilesTab);
            app.RFTTable = uitable(app.RFTGridLayout);
            app.RFTTable.ColumnName = {''; 'File'; 'Folder'};
            app.RFTTable.RowName = {};
            app.RFTTable.ColumnSortable = [false false false];
            app.RFTTable.ColumnEditable = [true false false];
            app.RFTFolderGridLayout = uigridlayout(app.RFTGridLayout);
            app.EditFieldLabel = uilabel(app.RFTFolderGridLayout);
            app.EditFieldLabel.Text = 'Edit Field';
            app.RFTFolderEditField = uieditfield(app.RFTFolderGridLayout, 'text');
            app.RFTFolderEditField.ValueChangedFcn = createCallbackFcn(app, @RFTFolderEditFieldValueChanged, true);
            app.RFTFolderButton = uibutton(app.RFTFolderGridLayout, 'push');
            app.RFTFolderButton.ButtonPushedFcn = createCallbackFcn(app, @RFTFolderButtonPushed, true);
            app.RFTFolderButton.Icon = 'foldericon.gif';
            app.RFTFolderButton.Text = '';
            app.RFTButtonsGridLayout = uigridlayout(app.RFTGridLayout);
            app.RFTButtonConvert = uibutton(app.RFTButtonsGridLayout, 'push');
            app.RFTButtonConvert.ButtonPushedFcn = createCallbackFcn(app, @RFTButtonConvertPushed, true);
            app.RFTButtonConvert.Text = 'Convert';
            app.RTFButtonLoad = uibutton(app.RFTButtonsGridLayout, 'push');
            app.RTFButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @RTFButtonLoadPushed, true);
            app.RTFButtonLoad.Text = 'Load';
            app.LoadedLinesTab = uitab(app.TabGroup);
            app.LoadedLinesTab.Title = 'Loaded lines';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.ProcessGrid = uigridlayout(app.ProcessTab);
            app.ProcessingPanel = uipanel(app.ProcessGrid);
            app.ProcessingPanel.Title = 'Processing';
            app.ProcessingGrid = uigridlayout(app.ProcessingPanel);
            app.GridButton = uibutton(app.ProcessingGrid, 'push');
            app.GridButton.ButtonPushedFcn = createCallbackFcn(app, @GridButtonPushed, true);
            app.GridButton.Text = 'Grid';
            app.GridresolutionmEditFieldLabel = uilabel(app.ProcessingGrid);
            app.GridresolutionmEditFieldLabel.Text = 'Grid resolution (m)';
            app.GridresolutionmEditField = uieditfield(app.ProcessingGrid, 'numeric');
            app.GridresolutionmEditField.Limits = [0 Inf];
            app.GridresolutionmEditField.Value = 1;
            app.AnalyzeButton = uibutton(app.ProcessingGrid, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.DisplayPanel = uipanel(app.ProcessGrid);
            app.DisplayPanel.Title = 'Display';
            app.DisplayGrid = uigridlayout(app.DisplayPanel);
            app.DataDisplayedDropDownLabel = uilabel(app.DisplayGrid);
            app.DataDisplayedDropDownLabel.Text = 'Data Displayed';
            app.DataDisplayedDropDown = uidropdown(app.DisplayGrid);
            app.DataDisplayedDropDown.Items = {'Bathy', 'BS', 'Slope', 'Pulse Mode'};
            app.DataDisplayedDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDisplayedDropDownValueChanged, true);
            app.DataDisplayedDropDown.Value = 'Bathy';
            app.ShowHideAnalysisResultsButton = uibutton(app.DisplayGrid, 'state');
            app.ShowHideAnalysisResultsButton.ValueChangedFcn = createCallbackFcn(app, @ShowHideAnalysisResultsButtonValueChanged, true);
            app.ShowHideAnalysisResultsButton.Text = 'Show/Hide Analysis Results';
            app.AnalysisResultsPanel = uipanel(app.ProcessGrid);
            app.AnalysisResultsPanel.Title = 'Analysis Results';
            app.Label = uilabel(app.AnalysisResultsPanel);
            app.Label.Text = '';
            app.Map = uiaxes(app.MainFigureGrid);
            zlabel(app.Map, 'Z')
            app.Map.XTickLabel = '';
            app.Map.YTickLabel = '';
            app.RFTContextMenu = uicontextmenu(app.MainFigure);
            % Assign app.RFTContextMenu
            app.RFTTable.ContextMenu = app.RFTContextMenu;
            app.SelectallMenu = uimenu(app.RFTContextMenu);
            app.SelectallMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectallMenuSelected, true);
            app.SelectallMenu.Text = 'Select all';
            app.DeselectallMenu = uimenu(app.RFTContextMenu);
            app.DeselectallMenu.MenuSelectedFcn = createCallbackFcn(app, @DeselectallMenuSelected, true);
            app.DeselectallMenu.Text = 'Deselect all';
            app.InverseselectionMenu = uimenu(app.RFTContextMenu);
            app.InverseselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @InverseselectionMenuSelected, true);
            app.InverseselectionMenu.Text = 'Inverse selection';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ristretto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end